classdef SaccadeAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_4                         matlab.ui.container.Panel
        UpdateButton                    matlab.ui.control.Button
        BlinkoffsetEditFieldLabel       matlab.ui.control.Label
        BlinkoffsetEditField            matlab.ui.control.NumericEditField
        ScreenResolutionEditFieldLabel  matlab.ui.control.Label
        ScreenResolutionCols            matlab.ui.control.NumericEditField
        ScreenResolutionRows            matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        DistanceeyescreenLabel          matlab.ui.control.Label
        eyeScreenDistance               matlab.ui.control.NumericEditField
        ScreenSizeLabel                 matlab.ui.control.Label
        ScreenWidth                     matlab.ui.control.NumericEditField
        ScreenHeight                    matlab.ui.control.NumericEditField
        xEditFieldLabel_2               matlab.ui.control.Label
        MaxLatencySpinnerLabel          matlab.ui.control.Label
        MaxLatencySpinner               matlab.ui.control.Spinner
        MinLatencySpinnerLabel          matlab.ui.control.Label
        MinLatencySpinner               matlab.ui.control.Spinner
        MaxDurationSpinnerLabel         matlab.ui.control.Label
        MaxDurationSpinner              matlab.ui.control.Spinner
        SaccTypeDropDownLabel           matlab.ui.control.Label
        SaccTypeDropDown                matlab.ui.control.DropDown
        MinDurationSpinnerLabel         matlab.ui.control.Label
        MinDurationSpinner              matlab.ui.control.Spinner
        MaxAmplitudeSpinnerLabel        matlab.ui.control.Label
        MaxAmplitudeSpinner             matlab.ui.control.Spinner
        MinAmplitudeSpinnerLabel        matlab.ui.control.Label
        MinAmplitudeSpinner             matlab.ui.control.Spinner
        Image                           matlab.ui.control.Image
        NeuroSenseJointLabSienaSIItalyLabel  matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        DeviceDropDownLabel             matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        ExperimentDropDownLabel         matlab.ui.control.Label
        ExperimentDropDown              matlab.ui.control.DropDown
        CurrentFileEditField            matlab.ui.control.EditField
        SelectFileButton                matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        RemovereturnsaccadesCheckBox    matlab.ui.control.CheckBox
        refreshMetrics                  matlab.ui.control.Button
        refreshmetricsLabel             matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        RemoveButton                    matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        AddButton                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        matCheckBox                     matlab.ui.control.CheckBox
        figCheckBox                     matlab.ui.control.CheckBox
        PhaseplaneCheckBox              matlab.ui.control.CheckBox
        SavereportButton                matlab.ui.control.StateButton
        MainsequenceCheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        experiment % Description
    end
    
    methods (Access = private)
        
        function toggleGUI(app)
            enableString = get(app.UITable, 'Enable');
            isEnabled = strcmp(lower(enableString), 'on');
            
            if ~isEnabled                
                set(app.ExperimentDropDown, 'Enable', 'on')
                set(app.AddButton, 'Enable', 'on')
                set(app.UITable, 'Enable', 'on')
                set(app.UpdateButton, 'Enable', 'on')
                set(app.RemovereturnsaccadesCheckBox, 'Enable', 'on')
                set(app.RemoveButton, 'Enable', 'on')
                set(app.Button, 'Enable', 'on')
                set(app.SavereportButton, 'Enable', 'on')
%                 set(app.Panel_4, 'Enable', 'on')
%                 set(app.MinAmplitudeSpinner, 'Enable', 'on')
%                 set(app.MaxDurationSpinner, 'Enable', 'on')
%                 set(app.MinDurationSpinner, 'Enable', 'on')
%                 set(app.MaxAmplitudeSpinner, 'Enable', 'on')
%                 set(app.MinAmplitudeSpinnerLabel, 'Enable', 'on')
%                 set(app.MaxDurationSpinnerLabel, 'Enable', 'on')
%                 set(app.MinDurationSpinnerLabel, 'Enable', 'on')
%                 set(app.MaxAmplitudeSpinnerLabel, 'Enable', 'on')
%                 set(app.MinLatencySpinnerLabel, 'Enable', 'on')
%                 set(app.MaxLatencySpinnerLabel, 'Enable', 'on')
                set(app.AddButton, 'Enable', 'on')
                set(app.figCheckBox, 'Enable', 'on')
                set(app.MainsequenceCheckBox, 'Enable', 'on')
                set(app.matCheckBox, 'Enable', 'on')
                set(app.SaccTypeDropDown, 'Enable', 'on')
                set(app.ResetButton, 'Enable', 'on')
                set(app.PhaseplaneCheckBox, 'Enable', 'on')
                set(app.refreshMetrics, 'Enable', 'on')
                set(app.refreshmetricsLabel, 'Enable', 'on')

                set(app.RemovereturnsaccadesCheckBox, 'Enable', 'on')
                app.RemovereturnsaccadesCheckBox.Value = 0;
                app.RemovereturnsaccadesCheckBox.Text = 'Remove return saccades';
                
            else
                set(app.ExperimentDropDown, 'Enable', 'off')
                set(app.AddButton, 'Enable', 'off')
                set(app.UITable, 'Enable', 'off')
                set(app.RemovereturnsaccadesCheckBox, 'Enable', 'off')
                set(app.RemoveButton, 'Enable', 'off')
                set(app.UpdateButton, 'Enable', 'off')

                set(app.Button, 'Enable', 'off')
                set(app.SavereportButton, 'Enable', 'off')
%                 set(app.Panel_4, 'Enable', 'off')
%                 set(app.MinAmplitudeSpinner, 'Enable', 'off')
%                 set(app.MaxDurationSpinner, 'Enable', 'off')
%                 set(app.MinDurationSpinner, 'Enable', 'off')
%                 set(app.MaxAmplitudeSpinner, 'Enable', 'off')
%                 set(app.MinAmplitudeSpinnerLabel, 'Enable', 'off')
%                 set(app.MaxDurationSpinnerLabel, 'Enable', 'off')
%                 set(app.MinDurationSpinnerLabel, 'Enable', 'off')
%                 set(app.MaxAmplitudeSpinnerLabel, 'Enable', 'off')
%                 set(app.MinLatencySpinnerLabel, 'Enable', 'off')
%                 set(app.MaxLatencySpinnerLabel, 'Enable', 'off')
                set(app.AddButton, 'Enable', 'off')
                set(app.figCheckBox, 'Enable', 'off')
                set(app.MainsequenceCheckBox, 'Enable', 'off')
                set(app.matCheckBox, 'Enable', 'off')         
                set(app.ResetButton, 'Enable', 'off')
                set(app.SaccTypeDropDown, 'Enable', 'off')         
                set(app.PhaseplaneCheckBox, 'Enable', 'off')
                set(app.refreshMetrics, 'Enable', 'off')
                set(app.refreshmetricsLabel, 'Enable', 'off')

                set(app.RemovereturnsaccadesCheckBox, 'Enable', 'off')
                app.RemovereturnsaccadesCheckBox.Value = 0;
                app.RemovereturnsaccadesCheckBox.Text = 'Remove return saccades';


            end
        end
        
        
        function plot_signal(app)
            
%             app.UIAxes.cla
            cla(app.UIAxes)

            hold(app.UIAxes, 'on')
            
            gaze_color = [0 0 1];
            center_color = [0.5 0.5 0.5];
            target_color = [1 0 0];
            timestamp = app.experiment.datarecorded(:, 4);
            
            xtarget = (app.experiment.datarecorded(:, 5)  - app.experiment.settings.HorScreenRes/2) ./ app.experiment.settings.pixPerDegH;
            ytarget = (app.experiment.datarecorded(:, 6) - app.experiment.settings.VerScreenRes/2) ./ app.experiment.settings.pixPerDegV;
            
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                
                app.UITable.ColumnName = {'Gap ID';'Saccade ID'; 'Sample ID'; 'Time (start)'; 'Duration'; ...
                    'Amplitude (x)'; 'Peak velocity'; 'Mean velocity'; 'Saccade Type'; 'Saccade Group'; 'Gain'; ...
                    'Accuracy'; 'Latency'; 'minAcc'; ...
                    'maxAcc'; 'Skewness'; 'QRatio'; 'Abruptness_acc'; 'Abruptness_dec'};
                
                temp_data_recorded = (app.experiment.datarecorded(:, 1)  - app.experiment.settings.HorScreenRes / 2) ./ app.experiment.settings.pixPerDegH;
                temp_data_recorded_stim = (app.experiment.datarecorded(:, 5) - app.experiment.settings.HorScreenRes / 2) ./ app.experiment.settings.pixPerDegH;
                
                % Plot Gaze X
                s = plot( ...
                    app.UIAxes, ...
                    timestamp, ...
                    temp_data_recorded, ...
                    'Color', gaze_color);
                
                % Plot the center line
%                 yline(app.UIAxes, ...
%                     app.ScreenResolutionCols.Value/2, 'Color', center_color);
                yline(app.UIAxes, 0, 'Color', center_color);
                
                % Plot the Stimulus (Target)                
                plot( ...
                    app.UIAxes, ...
                    timestamp(xtarget~=0), ...
                    temp_data_recorded_stim(xtarget~=0), '.', ...
                    'Color', target_color);
                
                % Update graph's labels
                app.UIAxes.YLabel.String = 'Gaze X (deg)';
                app.UIAxes.Box = 'on';
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                
            end
            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1
                
                app.UITable.ColumnName = {'Gap ID'; 'Saccade ID'; 'Sample ID'; 'Time (start)'; 'Duration'; ...
                    'Amplitude (y)'; 'Peak velocity'; 'Mean velocity'; 'Saccade Type';'Saccade Group'; 'Gain'; ...
                    'Accuracy'; 'Latency'; 'minAcc'; ...
                    'maxAcc'; 'Skewness'; 'QRatio'; 'Abruptness_acc'; 'Abruptness_dec'};
                
                data_recorded = (app.experiment.datarecorded(:, 2)  - app.experiment.settings.HorScreenRes / 2) ./ app.experiment.settings.pixPerDegV;
                temp_data_recorded_stim = (app.experiment.datarecorded(:, 6) - app.experiment.settings.HorScreenRes / 2) ./ app.experiment.settings.pixPerDegV;
                
                % Plot Gaze Y
                s = plot( ...
                    app.UIAxes, ...
                    timestamp, ...
                    data_recorded, ...
                    'Color', gaze_color);
                
                %Plot the center line
%                 yline(app.UIAxes, ...
%                     app.ScreenResolutionRows.Value/2, 'Color', center_color);
                yline(app.UIAxes, 0, 'Color', center_color);
                
                % Plot the Stimulus (Target)                
                plot( ...
                    app.UIAxes, ...
                    timestamp(ytarget~=0), ...
                    temp_data_recorded_stim(ytarget~=0), '.', ...
                    'Color', target_color);
                
                % Update graph's labels
                app.UIAxes.YLabel.String = 'Gaze Y (deg)';
                app.UIAxes.Box = 'on';
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
            
            end
            
            app.UIAxes.Title.String = app.experiment.fname;
            s.ButtonDownFcn = @(~, e) GraphClicked(app, e.IntersectionPoint); % Matlab 2019b
        
        end
        
        function GraphClicked(app, coord)
            
            if ~isempty(app.experiment.saccadeList)
                % Matlab 2019b
                disp(coord(1))
            
                indexToFind = coord(1, 1);
            
                [~, add_sacc_index] = min(abs(app.experiment.saccadeList{:, {'s1'}} *  (1 / (app.experiment.Hz) * 1000) - indexToFind));
                app.experiment.add_sacc_index = add_sacc_index;
            
            end   
        end
        
        function updatetable(app)
            try
                add_column_type(app);
                
                if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
    %                 distColumn = 11;  
                      distColumn = 'distSacx';
                      aPeak1 = 'axPeak1_1';
                      aPeak2 = 'axPeak1_2';
                      skewness = 'skewness_x';
                      abruptness_acc = 'abruptness_x_acc';
                      abruptness_dec = 'abruptness_x_dec';
                      QRatio = 'Q_ratio_x';
    
                end   
                
                if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1            
    %                 distColumn = 12;
                      distColumn = 'distSacy';
                      aPeak1 = 'ayPeak1_1';
                      aPeak2 = 'ayPeak1_2';
                      skewness = 'skewness_y';
                      abruptness_acc = 'abruptness_y_acc';
                      abruptness_dec = 'abruptness_y_dec';
                      QRatio = 'Q_ratio_y';
                      
                end
                
                %manually removed saccades
                app.experiment.saccadeListSelected = ...
                    app.experiment.saccadeList;
                
                if ~isempty(app.experiment.deletedSaccades)
                    app.experiment.saccadeListSelected(app.experiment.deletedSaccades, :) = [];
                
                end        
                
                % apply saccadetype filtering
                if strcmp(app.SaccTypeDropDown.Value, "All") ~= 1
                    if strcmp(app.SaccTypeDropDown.Value, "10") == 1
                        sacctype = 10;
                        
                    elseif strcmp(app.SaccTypeDropDown.Value, "18") == 1
                        sacctype = 18;
                    
                    elseif strcmp(app.SaccTypeDropDown.Value, "8") == 1
                        sacctype = 8;
                    
                    end
                    
                    app.experiment.saccadeListSelected = ...
                        app.experiment.saccadeListSelected(...
                            abs(app.experiment.saccadeListSelected{:, {'saccade_type'}}) == sacctype, :);
                    
    %                 app.experiment.saccadeListSelected = ...
    %                     app.experiment.saccadeListSelected(...
    %                         app.experiment.saccadeListSelected(:, 46) == sacctype, :);
                
                end
                
                % apply min amplitude
                app.experiment.saccadeListSelected = ...
                    app.experiment.saccadeListSelected(...
                        app.experiment.saccadeListSelected{:, {eval(['''', distColumn, ''''])}} >= app.MinAmplitudeSpinner.Value, :);
                
                % apply max amplitude
                app.experiment.saccadeListSelected = ...
                    app.experiment.saccadeListSelected(...
                        app.experiment.saccadeListSelected{:, {eval(['''', distColumn, ''''])}} <= app.MaxAmplitudeSpinner.Value, :);
                
                % apply min duration
                app.experiment.saccadeListSelected = ...
                    app.experiment.saccadeListSelected(...
                        app.experiment.saccadeListSelected{:, {'durat'}} >= app.MinDurationSpinner.Value, :);
                
                % apply max duration
                app.experiment.saccadeListSelected = ...
                    app.experiment.saccadeListSelected(...
                        app.experiment.saccadeListSelected{:, {'durat'}} <= app.MaxDurationSpinner.Value, :);
              
                % apply min duration
                app.experiment.saccadeListSelected = ...
                    app.experiment.saccadeListSelected(...
                        app.experiment.saccadeListSelected{:, {'latency'}} >= app.MinLatencySpinner.Value, :);
                
                % apply max duration
                app.experiment.saccadeListSelected = ...
                    app.experiment.saccadeListSelected(...
                        app.experiment.saccadeListSelected{:, {'latency'}} <= app.MaxLatencySpinner.Value, :);
                    
    %             % finally, update the table
    %             app.UITable.Data = array2table( ...
    %                 app.experiment.saccadeListSelected(:, [51 2 3 45 14 distColumn 5 6 46 53 48 49 50])); %%% QUI aggiungere alla tabella, accelerazioni, vari ratio e nuovi parametri calcolati
            
                % finally, update the table
                app.UITable.Data = array2table( ...
                    app.experiment.saccadeListSelected{:, {'gap_id', ...
                    'scnt', 's1', 'col4', 'durat', ...
                    eval(['''', distColumn, '''']), 'vPeak', 'vMean', ...
                    'saccade_type', 'saccade_group',  'gain', 'accuracy', ...
                    'latency', eval(['''', aPeak1, '''']), ...
                    eval(['''', aPeak2, '''']), eval(['''', skewness, '''']), ...
                    eval(['''', QRatio, '''']), ...
                    eval(['''', abruptness_acc, '''']), ...
                    eval(['''', abruptness_dec, ''''])}}, ...
                    "VariableNames", {'Gap ID'; 'Saccade ID'; ...
                    'Sample ID'; 'Time (start)'; 'Duration'; ...
                        'Amplitude (y)'; 'Peak velocity'; ...
                        'Mean velocity'; 'Saccade Type'; ...
                        'Saccade Group'; 'Gain'; ...
                        'Accuracy'; 'Latency'; ...
                        eval(['''', aPeak1, '''']); ...
                        eval(['''', aPeak2, '''']); ...
                        eval(['''', skewness, '''']); ...
                        eval(['''', QRatio, '''']); ...
                        eval(['''', abruptness_acc, '''']); ...
                        eval(['''', abruptness_dec, ''''])});
                
    %                 (:, [51 2 3 45 14 distColumn 5 6 46 53 48 49 50])); %%% QUI aggiungere alla tabella, accelerazioni, vari ratio e nuovi parametri calcolati
            
        
            catch
                disp('Something went wrong while updating the table.... line 340')
                
            end
        end
        
        function update_settings(app)
            if strcmp(app.DeviceDropDown.Value, 'EyeLink Portable Duo') == 1
                app.experiment.settings.VEL_SACCADE_TH = 10; 
                app.experiment.settings.SACCADE_WINDOW = 200;
                app.experiment.settings.SACCADE_PCT_PEAK = 15;  
                app.experiment.settings.MIN_WINDOW = 20;
                
                app.experiment.settings.HorScreenRes = app.ScreenResolutionCols.Value;
                app.experiment.settings.VerScreenRes = app.ScreenResolutionRows.Value;
                app.experiment.settings.eyeScreenDistance = app.eyeScreenDistance.Value;
                
                
                app.experiment.settings.ScreenWidth = app.ScreenWidth.Value;
                app.experiment.settings.ScreenHeight = app.ScreenHeight.Value;
                
                % compute how many degrees  horizontal and vertical
                % formula to get degrees of visual angles of an object of size(l) at a distance d from the eye 
                % ATAN
                % deg = 2 * atan(l / (2 * d)) * 180 / pi
%                 degEye_hor = 2 * (2 * atand((app.experiment.settings.ScreenWidth / 2) / (2 * app.experiment.settings.eyeScreenDistance)));
%                 degEye_ver = 2 * (2 * atand((app.experiment.settings.ScreenHeight / 2) / (2 * app.experiment.settings.eyeScreenDistance)));
                
                degEye_hor = atand((0.5 * app.experiment.settings.ScreenWidth) / (app.experiment.settings.eyeScreenDistance));
                degEye_ver = atand((0.5 * app.experiment.settings.ScreenHeight) / (app.experiment.settings.eyeScreenDistance));
                
                % 1 / ((degEye_hor * 1) / (app.experiment.settings.HorScreenRes))
                % 1 / ((degEye_ver * 1) / (app.experiment.settings.VerScreenRes))
                
                % ACOS
                f  = app.experiment.settings.eyeScreenDistance;
                x1 = 0; % centre = middle of the screen
                x2 = app.experiment.settings.ScreenWidth / 2;
                
                y1 = 0; % centre = middle of the screen
                y2 = app.experiment.settings.ScreenHeight / 2;
                
                angle = acosd( (f*f + x1*x2 + y1*y2) / (sqrt( (f*f + x1*x1 + y1*y1) * (f*f + x2*x2 + y2*y2))));
                res_angle_hor = acosd( (f*f + x1*x2) / (sqrt( (f*f + x1*x1 ) * (f*f + x2*x2))))*2;
                res_angle_ver = acosd( (f*f + y1*y2) / (sqrt( (f*f + y1*y1) * (f*f + y2*y2))))*2;

                
                % Compute the horizontal and vertical px/degree ratio
%                 app.experiment.settings.pixPerDegH = 46;
%                 app.experiment.settings.pixPerDegV = 45;

                % res_angle_hor : res_px_hor = x : 1 px  --> x = (res_angle_hor * 1 px) / res_px_hor
%                 app.experiment.settings.pixPerDegH = 1 / ((res_angle_hor * 1) / (app.experiment.settings.HorScreenRes));
%                 app.experiment.settings.pixPerDegV = 1 / ((res_angle_ver * 1) / (app.experiment.settings.VerScreenRes));

                app.experiment.settings.pixPerDegH = 1 / (degEye_hor / (0.5 * app.experiment.settings.HorScreenRes));
                app.experiment.settings.pixPerDegV = 1 / (degEye_ver / (0.5 * app.experiment.settings.VerScreenRes));
                
                app.experiment.settings.target10_x_neg = 484;
                app.experiment.settings.target10_x_pos = 1436;
                app.experiment.settings.target18_x_neg = 98;
                app.experiment.settings.target18_x_pos = 1822;
                
                app.experiment.settings.target8_y_neg = 172;
                app.experiment.settings.target8_y_pos = 908;
                
                app.experiment.settings.target_x_centr = 960;                      
                app.experiment.settings.target_y_centr = 540;                      

            else if contains(app.DeviceDropDown.Value, 'ASL') == 1
                app.experiment.settings.VEL_SACCADE_TH = 10; 
                app.experiment.settings.SACCADE_WINDOW = 200;
                app.experiment.settings.SACCADE_PCT_PEAK = 15;  
                app.experiment.settings.MIN_WINDOW = 20;
                
                app.experiment.settings.pixPerDegH = 5.4456;
                app.experiment.settings.pixPerDegV = 8.9760;
                
%                  app.experiment.settings.HorScreenRes = app.ScreenResolutionCols.Value;
%                 app.experiment.settings.VerScreenRes = app.ScreenResolutionRows.Value;
%                 app.experiment.settings.eyeScreenDistance = app.eyeScreenDistance.Value;
%                 
%                 
%                 app.experiment.settings.ScreenWidth = app.ScreenWidth.Value;
%                 app.experiment.settings.ScreenHeight = app.ScreenHeight.Value;
%                 
%                 % compute how many degrees  horizontal and vertical
%                 % formula to get degrees of visual angles of an object of size(l) at a distance d from the eye 
%                 % ATAN
%                 % deg = 2 * atan(l / (2 * d)) * 180 / pi
%                 degEye_hor = 2 * (2 * atand((app.experiment.settings.ScreenWidth / 2) / (2 * app.experiment.settings.eyeScreenDistance)));
%                 degEye_ver = 2 * (2 * atand((app.experiment.settings.ScreenHeight / 2) / (2 * app.experiment.settings.eyeScreenDistance)));
%                 % 1 / ((degEye_hor * 1) / (app.experiment.settings.HorScreenRes))
%                 % 1 / ((degEye_ver * 1) / (app.experiment.settings.VerScreenRes))
%                 
%                 % ACOS
%                 f  = app.experiment.settings.eyeScreenDistance;
%                 x1 = 0; % centre = middle of the screen
%                 x2 = app.experiment.settings.ScreenWidth / 2;
%                 
%                 y1 = 0; % centre = middle of the screen
%                 y2 = app.experiment.settings.ScreenHeight / 2;
%                 
%                 angle = acosd( (f*f + x1*x2 + y1*y2) / (sqrt( (f*f + x1*x1 + y1*y1) * (f*f + x2*x2 + y2*y2))));
%                 res_angle_hor = acosd( (f*f + x1*x2) / (sqrt( (f*f + x1*x1 ) * (f*f + x2*x2))))*2;
%                 res_angle_ver = acosd( (f*f + y1*y2) / (sqrt( (f*f + y1*y1) * (f*f + y2*y2))))*2;
% 
%                 
%                 % Compute the horizontal and vertical px/degree ratio
% %                 app.experiment.settings.pixPerDegH = 46;
% %                 app.experiment.settings.pixPerDegV = 45;
% 
%                 % res_angle_hor : res_px_hor = x : 1 px  --> x = (res_angle_hor * 1 px) / res_px_hor
%                 app.experiment.settings.pixPerDegH = 1 / ((res_angle_hor * 1) / (app.experiment.settings.HorScreenRes));
%                 app.experiment.settings.pixPerDegV = 1 / ((res_angle_ver * 1) / (app.experiment.settings.VerScreenRes));
%                 

                app.experiment.settings.target10_x_neg = 74;
                app.experiment.settings.target10_x_pos = 185;
                app.experiment.settings.target18_x_neg = 28;
                app.experiment.settings.target18_x_pos = 231;
                app.experiment.settings.target8_y_neg = 47;
                app.experiment.settings.target8_y_pos = 192;
                app.experiment.settings.target_x_centr = 130;  
                app.experiment.settings.target_y_centr = 120;     
            
            end
            end
        end
        
        
        function saccade_report(app)
            
            update_settings(app)
            
            saccadeList = extract_saccades(app,...
                [],...
                [],...
                1 / app.experiment.Hz * 1000,...     
                app.experiment.Hz,...            
                app.experiment.settings.pixPerDegH,...          
                app.experiment.settings.pixPerDegV,...                  
                app.experiment.settings.VEL_SACCADE_TH,...              
                app.experiment.settings.SACCADE_WINDOW,...             
                100,...                         
                app.experiment.settings.SACCADE_PCT_PEAK,...            
                app.experiment.settings.MIN_WINDOW); 
            
            app.experiment.saccadeList = saccadeList;
            
            
        end
    
        function add_column_type(app)
            
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                xtarget = (app.experiment.datarecorded(:, 5) - app.experiment.settings.HorScreenRes / 2 ) ./ app.experiment.settings.pixPerDegH;
                
%                 xtarget(xtarget == 0) = app.experiment.settings.target_x_centr;
%                 xtarget(xtarget == app.experiment.settings.target_x_centr) = 0; % app.experiment.settings.target_x_centr;
%                 xtarget(xtarget == app.experiment.settings.target18_x_pos) = 18;
%                 xtarget(xtarget == app.experiment.settings.target18_x_neg) = -18;
%                 xtarget(xtarget == app.experiment.settings.target10_x_pos) = 10;
%                 xtarget(xtarget == app.experiment.settings.target10_x_neg) = -10;
                
                xtarget(xtarget>0) = ceil(xtarget(xtarget>0));
                xtarget(xtarget<0) = floor(xtarget(xtarget<0));
                
                ind = 1;
                flag = 0;
                while ind < length(xtarget)
                    
                    if flag == 0 
                        if xtarget(ind) ~= 0 % app.experiment.settings.target_x_centr
                            flag = 1;
                            curr_stim = xtarget(ind);                       
                        
                        else                   
                            ind = ind + 1;
                        
                        end
                    end

                    if flag == 1
                        [row, ~] = find(xtarget(ind : end) == 0, 1, 'first');
                        ind = ind + row - 1;
                        
                        [next_stim, ~] = find(xtarget(ind : end) ~= 0, 1, 'first');
                        
                        if curr_stim <  0 % app.experiment.settings.target_x_centr
                            xtarget(ind : ind + next_stim - 2) = curr_stim-0.5;
                        
                        else
                            xtarget(ind : ind + next_stim - 2) = curr_stim+0.5;
                        
                        end
                        
                        flag = 0;
                        ind = ind + next_stim - 1;
                        
                    end
                end 
                
                try
                    xtarget = xtarget(app.experiment.saccadeList{:, {'s1'}}); % app.experiment.saccadeList(:, 3));
                    saccade_group = xtarget;
                    saccade_group(saccade_group == -10 | ...
                              saccade_group == 10  | ... 
                              saccade_group == -15 | ...... 
                              saccade_group == -18 | ... 
                              saccade_group == 15  | ... 
                              saccade_group == 18  | ... 
                              saccade_group == -8 | ... 
                              saccade_group == 8) = 1;
                
                    saccade_group(saccade_group == -10.5 | ...
                              saccade_group == 10.5  | ... 
                              saccade_group == -15.5 | ...... 
                              saccade_group == -18.5 | ... 
                              saccade_group == 15.5  | ... 
                              saccade_group == 18.5  | ... 
                              saccade_group == -8.5 | ... 
                              saccade_group == 8.5) = 2;
                    
                          
%                     xtarget(xtarget == -23) = -18;
%                     xtarget(xtarget == 23) = 18;
%                     xtarget(xtarget == -10.5) = -10;
%                     xtarget(xtarget == 10.5) = 10;

                    xtarget(xtarget>0) = floor(xtarget(xtarget>0));
                    xtarget(xtarget<0) = ceil(xtarget(xtarget<0));
                    saccade_type = xtarget;
                
                catch
                    disp('No valid saccades detected')
                
                end                   
            end
            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1            
                ytarget = app.experiment.datarecorded(:, 6);
                                              
                ytarget(ytarget == app.experiment.settings.target_y_centr) = 0; % app.experiment.settings.target_y_centr;
                ytarget(ytarget == app.experiment.settings.target8_y_pos) = 8;
                ytarget(ytarget == app.experiment.settings.target8_y_neg) = -8;
                              
                
                ind = 1;
                flag = 0;
                while ind < length(ytarget)
                    
                    if flag == 0 
                        if ytarget(ind) ~= 0 % app.experiment.settings.target_y_centr
                            flag = 1;
                            curr_stim = ytarget(ind);                       
                        
                        else                   
                            ind = ind + 1;
                        
                        end
                    end

                    if flag == 1
                        [row, ~] = find(ytarget(ind : end) == 0, 1, 'first');
                        ind = ind + row - 1;
                        
                        [next_stim, ~] = find(ytarget(ind : end) ~= 0, 1, 'first');
                        
                        if curr_stim < app.experiment.settings.target_y_centr
                            ytarget(ind : ind + next_stim - 2) = curr_stim-3;
                        
                        else
                            ytarget(ind : ind + next_stim - 2) = curr_stim+3;
                        
                        end
                        
                        flag = 0;
                        ind = ind + next_stim - 1;
                                                
                    end               
                end 
                
                try
                    ytarget = ytarget(app.experiment.saccadeList{:, {'s1'}}); %ytarget(app.experiment.saccadeList(:, 3));
                    saccade_group = ytarget;
                    saccade_group(saccade_group == -8 | ... 
                                  saccade_group == 8) = 1;
                    
                    saccade_group(saccade_group == 5 | ... 
                                  saccade_group == -11) = 2;
                    
                    ytarget(ytarget == 5) = -8;
                    ytarget(ytarget == -11) = 8;
                    saccade_type = ytarget;
                
                catch
                    disp('No valid saccades detected')
                
                end   
                
            end
            
            try
                app.experiment.saccadeList.('saccade_type') = saccade_type;
                app.experiment.saccadeList.('saccade_group') = saccade_group;
            
            catch
            end
            
        end
            
            
        function add_column_gain(app)
           
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
%                 distColumn = 11;  
                  distColumn = 'distSacx';
                  
            end   
            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1            
%                 distColumn = 12;
                  distColumn = 'distSacy';
                  
            end
            
            if ~isempty(app.experiment.saccadeList)
                saccade_type = app.experiment.saccadeList{:, {'saccade_type'}};
                saccade_amplitude = app.experiment.saccadeList{:, {eval(['''', distColumn, ''''])}};
    
    %             saccade_amplitude = app.experiment.saccadeList(:, distColumn);
    %             saccade_type = app.experiment.saccadeList(:, 46);
    %             saccade_amplitude = app.experiment.saccadeList(:, distColumn);
                
                gain = saccade_amplitude * 0;
                gain(saccade_type==-23) = saccade_amplitude(saccade_type==-23) / 18;
                gain(saccade_type==23) = saccade_amplitude(saccade_type==23) / 18;
                gain(saccade_type==-18) = saccade_amplitude(saccade_type==-18) / 18;
                gain(saccade_type==18) = saccade_amplitude(saccade_type==18) / 18;
                gain(saccade_type==-15) = saccade_amplitude(saccade_type==-15) / 10;
                gain(saccade_type==15) = saccade_amplitude(saccade_type==15) / 10;
                gain(saccade_type==-10) = saccade_amplitude(saccade_type==-10) / 10;
                gain(saccade_type==10) = saccade_amplitude(saccade_type==10) / 10;
                gain(saccade_type==-11) = saccade_amplitude(saccade_type==-11) / 8;
                gain(saccade_type==11) = saccade_amplitude(saccade_type==11) / 8;
                gain(saccade_type==-8) = saccade_amplitude(saccade_type==-8) / 8;
                gain(saccade_type==8) = saccade_amplitude(saccade_type==8) / 8;
                
    %             app.experiment.saccadeList = [app.experiment.saccadeList gain];
                app.experiment.saccadeList.('gain') = gain;
            
            end
                
        end
        
        function add_column_accuracy(app)
                                    
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
%                 distColumn = 11;  
                  distColumn = 'distSacx';
                  
            end   
            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1            
%                 distColumn = 12;
                  distColumn = 'distSacy';
                  
            end
            
            if ~isempty(app.experiment.saccadeList)
                saccade_type = app.experiment.saccadeList{:, {'saccade_type'}};
                saccade_amplitude = app.experiment.saccadeList{:, {eval(['''', distColumn, ''''])}};
    
    %             saccade_amplitude = app.experiment.saccadeList(:, distColumn);
    %             saccade_type = app.experiment.saccadeList(:, 46);
    %             saccade_amplitude = app.experiment.saccadeList(:, distColumn);
                
                accuracy = saccade_amplitude * 0;
                accuracy(saccade_type==-23) = abs(saccade_amplitude(saccade_type==-23) - 18);
                accuracy(saccade_type==23) = abs(saccade_amplitude(saccade_type==23) - 18);
                accuracy(saccade_type==-18) = abs(saccade_amplitude(saccade_type==-18) - 18);
                accuracy(saccade_type==18) = abs(saccade_amplitude(saccade_type==18) - 18);
                accuracy(saccade_type==-15) = abs(saccade_amplitude(saccade_type==-15) - 10);
                accuracy(saccade_type==15) = abs(saccade_amplitude(saccade_type==15) - 10);
                accuracy(saccade_type==-10) = abs(saccade_amplitude(saccade_type==-10) - 10);
                accuracy(saccade_type==10) = abs(saccade_amplitude(saccade_type==10) - 10);
                accuracy(saccade_type==-11) = abs(saccade_amplitude(saccade_type==-11) - 8);
                accuracy(saccade_type==11) = abs(saccade_amplitude(saccade_type==11) - 8);
                accuracy(saccade_type==-8) = abs(saccade_amplitude(saccade_type==-8) - 8);
                accuracy(saccade_type==8) = abs(saccade_amplitude(saccade_type==8) - 8);
                
                app.experiment.saccadeList.('accuracy') = accuracy;
    %             app.experiment.saccadeList = [app.experiment.saccadeList accuracy];
                            
            end
        end

        function add_column_gap_id(app)
            if ~isempty(app.experiment.saccadeList)
                if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                    
                    xtarget = app.experiment.datarecorded(:, 5);
                    
                    gaps = [0 (xtarget(2:end)~=xtarget(1:end-1))'];
                    gaps_indices = find(gaps);
                    timestamp = app.experiment.datarecorded(:, 4);
                    gaps_times = timestamp(gaps_indices);
                    
                    saccade_times = app.experiment.saccadeList{:, {'col4'}}; %app.experiment.saccadeList(:, 45);
                    
                    gap_id = saccade_times * 0;
                    gaps_times = gaps_times(2 : 2 : end);
                    for i = 1 : length(app.experiment.saccadeList{:, {'i'}})
                        for indJ = 1 : length(gaps_times) - 1
                            if saccade_times(i) > gaps_times(indJ) && ...
                                    saccade_times(i) < gaps_times(indJ + 1)
                                
                                gap_id(i) = indJ;
                                
                            end
                        end
                    end
                end 


                if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1
                    
                    ytarget = app.experiment.datarecorded(:, 6);
                    
                    gaps = [0 (ytarget(2:end)~=ytarget(1:end-1))'];
                    gaps_indices = find(gaps);
                    timestamp = app.experiment.datarecorded(:, 4);
                    gaps_times = timestamp(gaps_indices);
                    
                    saccade_times = app.experiment.saccadeList{:, {'col4'}}; %app.experiment.saccadeList(:, 45);
                    
                    gap_id = saccade_times * 0;
                    gaps_times = gaps_times(2 : 2 : end);
    
                    for i = 1:length(app.experiment.saccadeList{:, {'i'}})
                        for indJ = 1 : length(gaps_times) - 1
                            if saccade_times(i) > gaps_times(indJ) && ...
                                    saccade_times(i) < gaps_times(indJ + 1) 
                                
                                gap_id(i) = indJ;
                                
                            end
                        end
                    end
                end 

                app.experiment.saccadeList.('gap_id') = gap_id;
    %             app.experiment.saccadeList = [app.experiment.saccadeList gap_id];
            end
        end
           
        function add_column_latency(app)
            
          if ~isempty(app.experiment.saccadeList)  
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                
                xtarget = app.experiment.datarecorded(:, 5);
                
                gaps = [0 (xtarget(2:end)~=xtarget(1:end-1))'];
                gaps_indices = find(gaps);
                timestamp = app.experiment.datarecorded(:, 4);
                gaps_times = timestamp(gaps_indices);
                
%                 if ~isempty(app.experiment.saccadeList)
                    saccade_times = app.experiment.saccadeList{:, {'col4'}}; %app.experiment.saccadeList(:, 45);
                    
                    latency = saccade_times * 0;
                    
                    for i = 1:length(app.experiment.saccadeList{:, {'i'}})
                        closest_gap = gaps_times(gaps_times < saccade_times(i));
                        if length(closest_gap) == 0
                            latency(i) = 0;
                        
                        else
                            closest_gap = closest_gap(end);
                            latency(i) = saccade_times(i) - closest_gap;
                        
                        end
                    end
%                 end
            end             
            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1
                
                ytarget = app.experiment.datarecorded(:, 6);
                
                gaps = [0 (ytarget(2:end)~=ytarget(1:end-1))'];
                gaps_indices = find(gaps);
                timestamp = app.experiment.datarecorded(:, 4);
                gaps_times = timestamp(gaps_indices);
                
                saccade_times = app.experiment.saccadeList{:, {'col4'}}; %saccade_times = app.experiment.saccadeList(:, 45);
                
                latency = saccade_times * 0;
                
                for i = 1:length(app.experiment.saccadeList{:, {'i'}})
                    closest_gap = gaps_times(gaps_times < saccade_times(i));
                    if length(closest_gap) == 0
                        latency(i) = 0;
                    
                    else
                        closest_gap = closest_gap(end);
                        latency(i) = saccade_times(i) - closest_gap;
                    
                    end
                end
            end 

            app.experiment.saccadeList.('latency') = latency;
%             app.experiment.saccadeList = [app.experiment.saccadeList latency];
          end
            
        end
    
    end
    
    methods (Access = public)
        
        function DATA = deblink_interp(app)

            DATA = app.experiment.datarecorded_raw;
            
            % Define some minimum and maximum data expected in the
            % registration for gaze x, gaze y and pupil, respectively.
            minData = [1 1 1];
            maxData = [1920 1080 999999];            
            Km = min([size(minData, 2) size(maxData, 2)]);
            
            % Get DATA shape and add a column of ones
            [n, m] = size(DATA);
            DATA = [DATA, ones(n, 1)];

            % Initialize list of blink's point
            blinks = [];
            
            %
            last = [0 0 0];  
            blinkStatus = 0; 

            %%% BLINK DETECTION
            
            % We extend missing data ranges 
            offset = app.BlinkoffsetEditField.Value;
            
            % For each gaze point...
            for i = 1 : n
                
                % check if the values are in the valid ranges
                valid = true;                
                for j = 1 : Km                                   
                    valid = (valid) && (DATA(i, j) > minData(j)) && (DATA(i, j) <= maxData(j));
                    if ~(valid)  
                        break;                        
                    end
                end
                
                if valid 
                    
                    % If the data are all valid and the blink status is ON, it means
                    % that a blink istance just finished. Therefore blink information
                    % relative to the end index and coordinates, x and y angular 
                    % coefficients and constants defining the line of the blink istances.
                    if (blinkStatus == 1) 
                        blinkStatus = 0;
                        [nb, ~] = size(blinks); % mb 
                        blinks(nb).endIdx = min([i+offset n]); %#ok<*AGROW>
                        blinks(nb).endX = DATA(i, 1);
                        blinks(nb).endY = DATA(i, 2);
                        blinks(nb).mx = (blinks(nb).endX - blinks(nb).startX) / (blinks(nb).endIdx - blinks(nb).startIdx);
                        blinks(nb).cx = blinks(nb).endX - blinks(nb).mx * blinks(nb).endIdx;
                        blinks(nb).my = (blinks(nb).endY - blinks(nb).startY) / (blinks(nb).endIdx - blinks(nb).startIdx);
                        blinks(nb).cy = blinks(nb).endY - blinks(nb).my * blinks(nb).endIdx; 
                        
                    end
                    
                    last = DATA(i, :); % Overwrite the last variable with the data of the current frame.
                    
                else
                    
                    % If some of the data is not valid (blink found) check if the blink
                    % status is OFF
                    if (blinkStatus == 0) 
                        blinkStatus = 1; % Switch the status of the blink to ON (the next 
                                         % frame of the same blink will not pass this control 
                        
                        % Store the information of the detected blink
                        blinks = [blinks;  struct('startIdx', max([1 i-offset]), ...
                                                  'startX', last(1),...
                                                  'startY', last(2),...
                                                  'endIdx', i, ...
                                                  'endX', last(1),...
                                                  'endY', last(2),...
                                                  'mx',0,...
                                                  'cx',0,...
                                                  'my',0,...
                                                  'cy',0)];                                                 
                    end
                end
            end
            
            [nb, ~] = size(blinks); % mb
            id = 0;
            
            %%% DEBLINKING
            
            % For each blink...
            for i = 1 : nb
                
                % For each gaze point inside the selected blink...

                for ii = blinks(i).startIdx : blinks(i).endIdx
                    
                    % If the current blink is characterized by a line 
                    % having an angular velocity or an costant different 
                    % from zero, overwrite the gaze data at the 
                    % corrisponding row index and increment the counter 
                    % taking account of the changing to the gaze data.
                    
                    if ~(blinks(i).mx == 0) || ~(blinks(i).cx == 0)
                        id = id + 1;
                        DATA(ii, 1) = ii * blinks(i).mx + blinks(i).cx; 
                        DATA(ii, m + 1) = 0;                    
                    end
                    
                    if ~(blinks(i).my == 0) || ~(blinks(i).cy == 0)
                        id = id + 1;
                        DATA(ii, 2) = ii * blinks(i).my + blinks(i).cy; 
                        DATA(ii, m + 1) = 0;                    
                    end
                end
            end
            
            %%% FILTERING GAZE DATA (butterworth filter)
            DATA(:, 1) = filter_butterworth(app, DATA(:, 1)); 
            DATA(:, 2) = filter_butterworth(app, DATA(:, 2)); 
            
            datax = DATA(:, 1);
            datay = DATA(:, 2);
            
            datax = [datax(3 + 1 : length(datax), :); zeros(3, 1)]; 
            datay = [datay(3 + 1 : length(datay), :); zeros(3, 1)]; 
            
            DATA(:, 1) = datax;
            DATA(:, 2) = datay;   

        end
        
        function results = filter_butterworth(app, dataIn)
                
            n = 3; % Assign to the variable n the order of the butterworth filter
            WNiquist = app.experiment.Hz/ 2; % Define the analysis Nyquist pulsation
            Wn = 25 / WNiquist; % Define the cut pulsation          
            ftype = 'low';  % Define the filter type
            [z, p, k] = butter(n, Wn, ftype); % Compute the butterworth filter parameters
            [sos, g] = zp2sos(z, p, k); % Find the zero poles for the filter
            Hd = dfilt.df2sos(sos, g);  % Create the butterworth filter with the parameters previously defined
            results = filter(Hd, dataIn); % Filter the input data 

        end
        
        function dataOut = filter_butterworth_vel(app, dataIn)
 
            % dataOut = filter_butterworth(dataIn) filter the input data through a
            % butterworth filter.
            % 
            % dataIn       doubles     Nx7     data to be filtered
            %
            % dataOut      doubles     Nx7     filtered data
            % 
            % Author:      Pamela Federighi
            % Time-stamp:  2011-02-13 
            % E-mail:      federighi@unisi.it
            % Comments made by Valeria Serchi Mar 2016
            
            n = 3; % Assign to the variable n the order of the butterworth filter
            WNiquist = app.experiment.Hz / 2; % Define the analysis Nyquist pulsation
            Wn = 40 / WNiquist; % Define the cut pulsation          
            ftype = 'low';  % Define the filter type
            [z, p, k] = butter(n, Wn, ftype); % Compute the butterworth filter parameters
            [sos, g] = zp2sos(z, p, k); % Find the zero poles for the filter
            Hd = dfilt.df2sos(sos, g);  % Create the butterworth filter with the parameters previously defined
            % h = fvtool(Hd);	% Launch the filter visualization tool              
            % set(h, 'Analysis', 'freq') % Set the axis of the filter visualization tool
            
            dataOut = filter(Hd, dataIn); % Filter the input data
        end

        function vectorOut = filter_threshold_vel(app, vectorIn, rifThreshold)
         
            % vectorOut = filter_threshold_vel(vectorIn, rifThreshold) return a vector
            % equal to the input signal except for the indexes found to have values >=
            % or <0 to the rifThreshol variable (that are set to zero).
            % 
            % vectorIn      dobles      Nx1  gaze vector 
            % 
            % rifThreshold  doubles     1x1   velocity threshold
            % 
            % vectorOut     doubles     Nx1   filtered gaze vector
            %                                
            % 
            % Author:      Pamela Federighi
            % Time-stamp:  2011-02-13 
            % E-mail:      federighi@unisi.it
            % Comments made by Valeria Serchi Mar 2016
            
            x = vectorIn;         % Assign to the variable x the input gaze vector
            rif = rifThreshold;   % Assign to the variable rif the vale of the input variable rifThreshold
            [n, ~] = size(x);     % Assign to the variable n the number of the columns of the gaze vector
            y = zeros(n, 1);      % Initialize the vector y to a vector of zeros having the same size of 
                                  % the input gaze vector
            
            % Make a loop on the rows of the input gaze vector. If a value within +-rif 
            % is found, it is assigned to the corresponding row of the vector y  
            for i = 1 : n
                if x(i) >= rif || x(i) <= -rif
                    y(i) = x(i);
            
                end
            end

            vectorOut = y; % Assign to the output variable vectorOut the value of the variable y.
        end
        
        function outmat = risampledata(app, inmat, freq)
        
            % outmat = risampledata(inmat, freq) 
            % 
            % inmat   doubles Nx7  x-, y- and z-coordinates and time-stamp of two synced signals 
            %                      to be resampled. 
            %  
            % freq    doubles 1x1  new sample frequency the input signal has to be resampled at. 
            % 
            % outmat  doubles Nx4  x-, y- and z-coordinates and time-stamp of a signal 
            %                      to be resampled.   
            % 
            % Author:      Pamela Federighi
            % Time-stamp:  2011-02-13 
            % E-mail:      federighi@unisi.it
            % Comments made by Valeria Serchi Mar 2016
            toberemoved = [];
            for i=2:length(inmat(:, 4))
                if inmat(i, 4) == inmat(i-1, 4)
                    toberemoved = [toberemoved i];
                end
            end
            inmat(toberemoved, :) = [];
            
            t = inmat(:, 4);                % Assign the time-stamp of the signals to the variable t [ms]
            passot = (1 / freq) * 1000;     % Assign to the variable passot the time step [ms]
            st = min(t);                    % Assign to the variable st the time step of the input signals [ms]
            en = max(t) + passot;           % Assign to the variable en the maximum value of the time-stamp of 
                                            % the new time vector (maximum value of the time of the signals plus 
                                            % the passot)
            tnew = (st : passot : en)';     % Assign to the variable tnew the time vector for the resampled signals
            
            % Resample the input signals using the new time-stamp vector
%             disp(tnew);
            y1 = interp1(t,inmat(:,1),tnew,'linear','extrap'); 
            y2 = interp1(t,inmat(:,2),tnew,'linear','extrap'); 
            y3 = interp1(t,inmat(:,3),tnew,'linear','extrap'); 
            
            y5 = interp1(t,inmat(:,5),tnew,'nearest','extrap'); 
            y6 = interp1(t,inmat(:,6),tnew,'nearest','extrap'); 
            y7 = interp1(t,inmat(:,7),tnew,'nearest','extrap'); 
            
            
            outmat=[y1, y2, y3, tnew, y5, y6, y7];
            
        end
        
        
        function phase_plane = compute_phase_plane(app, filename)
            
            if contains(app.ExperimentDropDown.Value, 'Horizontal')
                col = 1;
                pixPerDeg = app.experiment.settings.pixPerDegH;
                
            else 
                col = 2;
                pixPerDeg = app.experiment.settings.pixPerDegV;
                
            end
            
            phase_plane = [];
           
            pos_temp = app.experiment.datarecorded(:, col);
        
            % compte the velocity Vector
            a = [0.8024; -0.2022; 0.03904; -0.003732]; % Define the parameters for the 4 points velocity computation
            vel_temp = [0; diff(pos_temp)]; % Compute a 1-point velocity along the x-coordinate
            vels = zeros(-5 + length(pos_temp) - 4, 1); % Initialize to an empty vector the variable vxs
        
            % Computation of the 5-points x-velocity   
            for indDatox = 5 : length(pos_temp) - 4 % Make a loop over the rows 
                vel_temp(indDatox, 1) = 0;
             
                for nx = 1 : 4    
                  vels(indDatox, 1) = a(nx, 1) * ...
                      (pos_temp(indDatox + nx) - ...
                      pos_temp(indDatox - nx));
                  
                  vel_temp(indDatox, 1) = vel_temp(indDatox, 1) + ...
                      vels(indDatox, 1);
                
                end
            end
            
            vel_temp = abs(filter_butterworth_vel(app, vel_temp)); 
                
            h1 = figure(1);
            hold on
            
            h2 = figure(2);
            hold on
            
            if ~isempty(app.experiment.saccadeListSelected)
                for ind = 1 : height(app.experiment.saccadeListSelected)
                    t1 = app.experiment.saccadeListSelected{ind, {'s1'}};
                    t2 = app.experiment.saccadeListSelected{ind, {'s2'}};      
                    vel_temp_deg = (vel_temp(t1 : t2) * app.experiment.Hz) / pixPerDeg;
                    pos_temp_deg = pos_temp(t1 : t2) / pixPerDeg;
                    
                    vel_temp_deg = vel_temp_deg; % - min(vel_temp_deg);
                    norm_vel_temp_deg = vel_temp_deg / max(vel_temp_deg);
                    pos_temp_deg = pos_temp_deg - min(pos_temp_deg);
                    norm_pos_temp_deg = pos_temp_deg / max(pos_temp_deg);
                    
                    if norm_pos_temp_deg(1) == 1
                        norm_pos_temp_deg = 1 - norm_pos_temp_deg;
                        
                    end
                                    
                    str = num2str(app.experiment.saccadeListSelected{ind, {'saccade_type'}});
    
                    if app.experiment.saccadeListSelected{ind, {'saccade_type'}} < 0
                        str = ['M', str(end : end - 1)];
                        
                    else
                        str = ['P', str];
                    
                    end
                    
                    phase_plane.(str).(['sacc_', num2str(ind)])(:, 1) = pos_temp_deg;
                    phase_plane.(str).(['sacc_', num2str(ind)])(:, 2) = vel_temp_deg;
    
                    phase_plane.(str).(['norm_sacc_', num2str(ind)])(:, 1) = norm_pos_temp_deg;
                    phase_plane.(str).(['norm_sacc_', num2str(ind)])(:, 2) =  norm_vel_temp_deg;
                    
                    figure(1)
                    plot(norm_pos_temp_deg, vel_temp_deg)
    
                    figure(2)
                    plot(norm_pos_temp_deg, ...
                         norm_vel_temp_deg)
                    
                end

                saveas(h1, [filename, '_Vel.fig'])
                close(h1)
                saveas(h2, [filename, '_normVel.fig'])  
                close(h2)
            
                save([filename, '_phase_plane'], "phase_plane")
            
            end
        end
        

        function saccadeList = extract_saccades(app,...
            index,...
            ROI,... 
            acqIntvl,...            
            acqRate,...             
            pixPerDegH,...          
            pixPerDegV,...          
            saccadeVelThresh,...    
            saccadeWindow,...       
            saccadeMinFixDuration,... 
            saccadePctPeak,...      
            saccadeMinDuration)    %#ok<INUSL>
        
        % saccadeList = extract_saccades(data, index, ROI, acqIntvl, acqRate, pixPerDegH, pixPerDegV, saccadeVelThresh, saccadeWindow,
        % saccadeMinFixDuration, saccadePctPeak, saccadeMinDuration) find the variations in the position of the stimulus on the screen.
        % 
        % data                      doubles     Nx7     analysis data organized as x-coord and of the 
        %                                               gaze
        %
        % index                     doubles     1x1     threshold of variation (over this threshold the script
        %                                               detect a variation in the position of the stimulus)
        % 
        % ROI                    
        % 
        % acqIntvl                  doubles     1x1     acquisition interval value
        % 
        % acqRate                   doubles     1x1     acquisition rate
        % 
        % pixPerDegH                doubles     1x1     horizontal ratio px/deg 
        % 
        % pixPerDegV                doubles     1x1     vertical ratio px/deg 
        % 
        % saccadeVelThresh          doubles     1x1     velocity threshold for the saccades identification
        % 
        % saccadeWindow                         1x1     not used in this version of the function
        % 
        % saccadeMinFixDuration     doubles     1x1     minimum duration 
        % 
        % saccadePctPeak            doubles     1x1     threshold of variation (over this threshold the script
        %                                               detect a variation in the position of the stimulus)
        % 
        % saccadeMinDuration        doubles     1x1     minimum duration threshold for saccades identification
        %
        % saccadeList                   [{}]    Mx1     information about the saccades recognized to be occurring
        %                                               during the analysis
        % 
        % Author:      Pamela Federighi (modified by Dario Zanca)
        % Time-stamp:  2011-02-13 
        % E-mail:      federighi@unisi.it
        % Comments made by Valeria Serchi Mar 2016
        
        data = app.experiment.datarecorded;
        
        if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                
            saccadeList = []; % Initialize the variable saccadeList to an empty array
            
            % If the variable index is empty 
            if isempty(index)
                index = [1 size(data, 1)];
            
            end
                
            isFixROI = ~isempty(ROI);   % Assign the logic not of the isempty(ROI) to the variable isFixROI 
            vCrit = saccadeVelThresh;   % Assign to the variable vCrit the value of the threshold for the saccades detection
            trials = size(index, 1);    % Initialize the variable trials to the rows number of the variable index  
            fLen = round(saccadeMinFixDuration / acqIntvl); % Assign the mimimum lenght to detect a fixation to the variable 
                                                            % fLen computed as the ratio between the input threshold and the 
                                                            % acquisition time step
            
            % Get the data of the gaze from the input data variable
            for i = 1 : trials
            %     sprintf('update %f Calculating Trial %i', 100 * i / trials, i) % Print out he number of the trial being processed
                t1 = index(i, 1); % Assign to the variable t1 the number of rows of the input matrix data
                t2 = index(i, 2); % Assign to the variable t2 the number of columns of the input matrix data
                trialx = []; % Initialize to an empty vector the variable trialx
                trialy = []; % Initialize to an empty vector the variable trialy
                 
                for ii = t1 : t2
                    
                    % Check if the variable data is a structure. If so, the gaze data 
                    % is stored in the rows of tha variable data corresponding to the 
                    % ii-th trial and to its subfields x and y.If not, it means that
                    % the variable data stores the values relative to only one trial
                    % and the x- and y-coordinates of the gaze are stored in the first
                    % and second columns of the input matrix data. 
                    if (isstruct(data(ii)))
                        trialx = [trialx; data(ii).x]; %#ok<AGROW>
                        trialy = [trialy; data(ii).y]; %#ok<AGROW>
                    
                    else
                        trialx = [trialx; data(ii,1)]; %#ok<AGROW>
                        trialy = [trialy; data(ii,2)]; %#ok<AGROW>
                    
                    end
                end
               
                tT = 1;  % Initialize the variable tT to the value 1
                
                if isFixROI      
                    g1 = tT;
                    g2 = tT + fLen - 1;         
                    
                    if g2 > t2 
                        g2 = t2; 
                    
                    end
                    
                    g = find(isfinite(trialx(g1 : g2)));  
                    inROI = is_in_ROI([trialx(g) trialy(g)], ROI);
                    
                else
                    inROI = 1;
                    
                end
                 
                if inROI
            %  --------------------------------------------------------         
            %  Find the absolute velocity vector (ILAB pixels/sample),
            %  ALGORITMO a 1 punto
            %  --------------------------------------------------------         
            %         vx = [0; diff(trialx)];
            %         vy = [0; diff(trialy)];
            %         ax = [0; diff(vx)];
            %         ay = [0; diff(vy)];
            %        
            %         vabs = sqrt(vx.^2 + vy.^2);    %pixel
            %         aabs = sqrt(ax.^2 + ay.^2);    %pixel
            % 
            %
            %      % oppure, calcolo in degree, consderando assi orizzontale e verticale diversi: 
            %         vxt=(vx/pixPerDegH)*acqRate        %degree
            %         vyt=(vy/pixPerDegV)*acqRate        %degree
            %         
            %         vabs = sqrt(vxt.^2 + vyt.^2);      %degree
            %         aabs = sqrt(ax.^2 + ay.^2);        %pixel       
                    
                    a = [0.8024; -0.2022; 0.03904; -0.003732]; % Define the parameters for the 4 points velocity computation
                    vx = [0; diff(trialx)];% Compute a 1-point velocity along the x-coordinate
            % %         vxrif = vx;
                    vxs= zeros(-5 + t2 - 4, 1); % Initialize to an empty vector the variable vxs
                            
                    % Computation of the 5-points x-velocity   
                    for indDatox = 5 : t2 - 4 % Make a loop over the rows 
                        vx(indDatox, 1) = 0;
                     
                        for nx = 1 : 4    
                          vxs(indDatox, 1) = a(nx, 1) * (trialx(indDatox + nx) - trialx(indDatox - nx));
                          vx(indDatox, 1) = vx(indDatox, 1) + vxs(indDatox, 1);
                        
                        end
                    end
                    
                    vy = [0; diff(trialy)];% Compute a 1-point velocity along the x-coordinate
            % %         vyrif = vy;
                     vys = zeros(-5 + t2 - 4, 1); %%[] % Initialize to an empty vector the variable vys
            
                    % Computation of the 5-points y-velocity
                    for indDatoy = 5 : t2 - 4
                        vy(indDatoy, 1) =0 ;
                        
                        for ny = 1 : 4    
                              vys(indDatoy, 1) = a(ny, 1) * (trialy(indDatoy + ny) - trialy(indDatoy - ny));
                              vy(indDatoy, 1) = vy(indDatoy, 1) + vys(indDatoy, 1);
            
                        end
                    end
            
                    vx = filter_butterworth_vel(app, vx); % Filter the x-velocity vector through a butterworth filter
                    vy = filter_butterworth_vel(app, vy); % Filter the y-velocity vector through a butterworth filter
                    
                    vx = [vx(2 + 1 : length(vx), :); zeros(2, 1)]; % Cut the first two samples of the variable vx
                    vy = [vy(2 + 1 : length(vy), :); zeros(2, 1)]; % Cut the first two samples of the variable vy
                     
                    ax = [0; diff(vx)]; % Assign to the variable ax the acceleration of the signal along the x direction %pixel
                    ay = [0; diff(vy)]; % Assign to the variable ay the acceleration of the signal along the y direction %pixel
              
                    ax1 = [0; diff(vx)]; %px
                    ay1 = [0; diff(vy)]; %px
            %         
                    %%%%%
                    vx_abs = abs(vx);
                    ax_abs  = [0; diff(vx_abs )]; % Assign to the variable ax the acceleration of the signal along the x direction %pixel
                
                    vy_abs = abs(vy);
                    ay_abs  = [0; diff(vy_abs )]; % Assign to the variable ay the acceleration of the signal along the y direction %pixel
              
                    ax1_abs = [0; diff(vx_abs)]; %px
                    ay1_abs = [0; diff(vy_abs)]; %px
                                       
                    %%%%%       
                    
                    ax = [0; diff(vx)]; %degree
                    ay = [0; diff(vy)]; %degree
                    
                    vx = (vx * acqRate) / pixPerDegH; % Convert the vx from px to degree of visual angles
                    vy = (vy * acqRate) / pixPerDegV; % Convert the vy from px to degree of visual angles       

                    ax1 = (ax * acqRate * acqRate) / pixPerDegH; 
                    ay1 = (ay * acqRate * acqRate) / pixPerDegV; 

                    vabs = sqrt(vx .^ 2 + vy .^ 2); % Compute the absolute velocity of the signal     %degree / s
                    aabs = sqrt(ax .^ 2 + ay .^ 2); % Compute the absolute acceleration of the signal %pixel
                    aabs1 = sqrt(ax1 .^ 2 + ay1 .^ 2); % Compute the absolute acceleration of the signal %degree / s^2
                    
                    vxabs = sqrt(vx .^ 2); % Compute the absolute value of the x-velocity      %degree     
                    vyabs = sqrt(vy .^ 2); % Compute the absolute value of the y-velocity      %degree     
                
                    axabs = sqrt(ax .^ 2); %#ok<NASGU> % Compute the absolute value of the x-acceleration      %pixel     
                    ayabs = sqrt(ay .^ 2); %#ok<NASGU> % Compute the absolute value of the y-acceleration      %pixel    
                 
                    axabs1 = sqrt(ax1 .^ 2); %#ok<NASGU> % Compute the absolute value of the x-acceleration      %degree     
                    ayabs1 = sqrt(ay1 .^ 2); %#ok<NASGU> % Compute the absolute value of the y-acceleration      %degree    
                 
                    vCutoff = vCrit;   %#ok<NASGU>
                    vCritx = vCrit; % Assign to the variable vCritx the critical value 
                                    % of velocity used to detect a saccade
                    
                    vCrity = vCrit; % Assign to the variable vCrity the critical value 
                                    % of velocity used to detect a saccade
                    
                    vxthreshold = []; %#ok<NASGU> 
                    vythreshold = []; %#ok<NASGU> 
                    
                    % Call the function filter_threshold_vel to isolate the part of the
                    % signal with a velocity within the range of saccade (vCritx)
                    vxthreshold = filter_threshold_vel(app, vx, vCritx); % Call for the x-velocity threshold filter. Return a vector with null 
                                           % elements where the velocity of the signal is not within the range 
                                           % specified (vCritx). 
                              
                    vythreshold=filter_threshold_vel(app, vy, vCrity); % Call for the y-velocity threshold filter. Return a vector with null 
                                           % elements where the velocity of the signal is not within the range 
                                           % specified (vCrity). 
                  
                    mvxthreshold = sqrt(vxthreshold.^2); %#ok<NASGU> % Normalize the velocity threshold vector
                    mvythreshold = sqrt(vythreshold.^2); %#ok<NASGU> % Normalize the velocity threshold vector
                    
            % Plot the velocity vector (x)
            %                figure;
            %                hold on;
            %                vRange = acqIntvl*(1:length(vx));   % Define the temporal vector for the gaze [ms]       % range of vabs in ms.      
            %                plot(vRange/1000, vx, 'k'); % plot in pixel per secondi              
            %                hold on;
            %                plot(acqIntvl*[1 length(vx)]./1000, [ vCrit vCrit], 'b--');
            %                drawnow;
            %                pause(1);
            % 
            % Plot the normalized velocity vector (x)
            %                figure;
            %                hold on;
            %                vRange = acqIntvl*(1:length(vxabs));  % Define the temporal vector for the gaze [ms]       % range of vabs in ms.      
            %                plot(vRange/1000, vxabs, 'k');   % plot in pixel per secondi           
            %                hold on;
            %                plot(acqIntvl*[1 length(vxabs)]./1000, [ vCrit vCrit], 'b--');
            %                drawnow;
            %                pause(1);
             
            
                    % Find the critical index to identify the saccades        
                    iCrit = find(vxthreshold ~= 0); % Assign to the variable iCrit the 
                                                    % index of all the elements of vxthreshold 
                                                    % different from zero (saccades instances).
                   
                    % If elements equal to zero were found go on with the following
                    % computation
                    if ~isempty(iCrit) % If there are saccades
                        k = 1;  % Initialize the counter of the following while loop
                        scnt = 0;  % Initialize a counter to take count of the saccades number
                        s2 = 1;  
                        
                        while k <= length(iCrit)-3   %
                            
                           % Check if there are three consecutive indexes having a velocity within the threshold
                           if (iCrit(k) + 1 == iCrit(k + 1)) && (iCrit(k + 1) + 1 == iCrit(k + 2)) && (iCrit(k + 2) + 1 == iCrit(k + 3)) %& (iCrit(k+3)+1==iCrit(k+4)) & (iCrit(k+4)+1==iCrit(k+5))& (iCrit(k+5)+1==iCrit(k+6))& (iCrit(k+6)+1==iCrit(k+7)) & (iCrit(k+7)+1==iCrit(k+8))& (iCrit(k+8)+1==iCrit(k+9)) & (iCrit(k+9)+1==iCrit(k+10))    %condition if tre ara 10 index witn velocity > vCrit 
                               i1 = iCrit(k);   % Assign to i1 the index of the current saccade   
                               valuevx = sign(vxthreshold(i1)); % Assign to valuevx the sign of the elements at index i1
                               i2c = min(find(vxthreshold(i1 : end) == 0)); %#ok<MXFND> % Find the next index with a zero element 
                               i2c2 = min(find(sign(vxthreshold(i1 : end)) ~= valuevx)); %#ok<MXFND> % Find the next index with a sign of the velocity opposite 
                                                                                                     % to the velocity of the signal at the index i1
                               i2 = i1 + i2c - 2; % Define the final index where the vxthreshold goes back to zero
                               i2bis = i1 + i2c2 - 2; % Define the final index where the vxthreshold changes in the sign
                               i2 = min(i2, i2bis); % Overwrite i2 with the minimum index between the next change in sign 
                                                    % and the zeros in the vector vxthreshold.
                                                    
                               i2 = min(i2, length(vabs)); % If the index is longer than the length of the velocity vector, 
                                                           % overwrite it with the last index of th vector vabs.
                                
                               %  i2 = min(i2bis, length(vabs)); 
                             
                               vPeak = max(vabs(i1 : i2)); % Find the peak of the absolute velocity between the i1 and i2
                               
                               vxPeak = max(vxabs(i1 : i2)); % Find the peak of the x-velocity between the i1 and i2 
                               
                               vyPeak = max(vyabs(i1 : i2)); % Find the peak of the y-velocity between the i1 and i2
              
                               aPeak = [min(aabs(i1 : i2)), max(aabs(i1 : i2))]; %#ok<NASGU> % Find the peak of the absolute acceleration between the i1 and i2 px
                                                              
                               aPeak1 = [min(aabs1(i1 : i2)), max(aabs1(i1 : i2))]; % Find the peak of the absolute acceleration between the i1 and i2 deg
                               
                               axPeak = [min(ax(i1 : i2)), max(ax(i1 : i2))]; %#ok<NASGU> % Find the peak of the x-acceleration between the i1 and i2 px
                               
                               ayPeak = [min(ay(i1 : i2)), max(ay(i1 : i2))]; %#ok<NASGU> % Find the peak of the y-acceleration between the i1 and i2 px
                              
                               axPeak1 = [min(ax1(i1 : i2)), max(ax1(i1 : i2))]; % Find the peak of the x-acceleration between the i1 and i2 deg
                               
                               ayPeak1 = [min(ay1(i1 : i2)), max(ay1(i1 : i2))]; % Find the peak of the y-acceleration between the i1 and i2 deg
                               
                               iPeak = i1 + min(find(vabs(i1 : i2) == vPeak)) - 1;   %#ok<MXFND> % Find the index of the peak of the absolute 
                                                                                                 % velocity between the i1 and i2 
                                                                                                 
                               ixPeak = i1 + min(find(vxabs(i1 : i2) == vxPeak)) - 1; %#ok<MXFND> % Find the index of the peak of the  
                                                                                                  % x-velocity between the i1 and i2
                                                                                                  
                               iyPeak = i1 + min(find(vyabs(i1 : i2) == vyPeak)) - 1; %#ok<MXFND> % Find the index of the peak of the  
                                                                                                  % y-velocity between the i1 and i2
                               
                               %% Modification on 16 March 2022 - vserchi@gmail.com                                                                   
                               %% Find the indexes corresponding to the peak of the acceleration (min and max)
%                                if i1 == 552
%                                    disp('here')
%                                
%                                end
             
%                                [~, aix_max_Peak] = max(ax1_abs(i1 : i2)); % Find the index of the maximum ax acceleration
%                                aix_max_Peak = i1 + aix_max_Peak - 1; %#ok<NASGU> 
%                          
%                                [~, aix_min_Peak] = min(ax1_abs(i1 : i2)); % Find the index of the minumum ax acceleration
%                                aix_min_Peak = i1 + aix_min_Peak - 1; %#ok<NASGU>                                      
%                                                      
%                                [~, aiy_max_Peak] = max(ay1_abs(i1 : i2)); % Find the index of the maximum ay acceleration 
%                                aiy_max_Peak = i1 + aiy_max_Peak - 1; %#ok<NASGU>
%                                                              
%                                [~, aiy_min_Peak] = min(ay1_abs(i1 : i2)); % Find the index of the minumum ay acceleration
%                                aiy_min_Peak = i1 + aiy_min_Peak - 1; %#ok<NASGU> 
                                       
                               skewness_x = (ixPeak - i1) / (i2 - ixPeak);  % ref: Pamela Federighi's PhD thesis
                               abruptness_x_acc = ((vxPeak - vxabs(i1)) / (ixPeak - i1)) / ((vxPeak - vxabs(i1)) * (i2 - i1)/acqRate); % ref: Pamela Federighi's PhD thesis
                               abruptness_x_dec = ((vxPeak - vxabs(i2)) / (i2 - ixPeak)) / ((vxPeak - vxabs(i2)) * (i2 - i1)/acqRate); % ref: Pamela Federighi's PhD thesis

                               skewness_y = (iyPeak - i1) / (i2 - iyPeak); % ref: Pamela Federighi's PhD thesis
                               abruptness_y_acc = ((vyPeak - vyabs(i1)) / (iyPeak - i1)) / ((vyPeak - vyabs(i1)) * (i2 - i1)/acqRate); % ref: Pamela Federighi's PhD thesis
                               abruptness_y_dec = ((vyPeak - vyabs(i2)) / (i2 - iyPeak)) / ((vyPeak - vyabs(i2)) * (i2 - i1)/acqRate); % ref: Pamela Federighi's PhD thesis

                               %% End of modification
                               
                               s1 = i1;  % Assign to s1 the index of start of the current saccade (i1) 
                              
                               % Assign to s2 the ending index of the current saccade (i2)
                               if isempty(s2)
                                    s2 = length(vabs);
                                
                               else
                                    s2 = i2;
                                
                               end
                            
                               % Compute the time in ms corresponding to the starting
                               % and ending indexes of the current saccades
                               ts1 = s1 * acqIntvl;  % [ms]
                               ts2 = s2 * acqIntvl;  % [ms]
                               
                               %sprintf('ts2,ts1, saccadeMinDuration %i %i %i',ts2,ts1, saccadeMinDuration)
                                
                               % Check if the current saccade is a saccade. Check the
                               % duration of the signal within the starting and ending
                               % indexes of the current saccade.                
                            if ((ts2 - ts1) > saccadeMinDuration)
            
                                    iNaN = find(~isfinite(vabs(s1 : s2))); % Find the NaN elements of the signal 
                                                                           % within the times s1 and s2.
            
                                    pctInvalid = (length(iNaN)/(s2 - s1 + 1)) * 100;  % Compute the percentage of the NaN 
                                                                                      % values of the vector within the times 
                                                                                      % s1 and s2.
            
                                    iV = find(isfinite(vabs(s1:s2))); % Find the indexes of the finite elements of the signal 
                                                                      % within the times s1 and s2.
                                    iV = s1 + iV - 1; % Find the indexes of the finite elements of the signal 
                                                      % within the times s1 and s2 with respect to the entire 
                                                      % length of the gaze signal	       
                                   
                                    % Find the mean absolute velocity and acceleration
                                    % of the current saccade.   
                                    if ~isempty(iV)
                                       vMean = mean(vabs(iV));            
                                       aMean = mean(aabs(iV));             
            
                                    else
                                       % If no finite elements are found the mean
                                       % absolute velocity and acceleration are set to
                                       % NaN values.
                                       vMean = NaN;
                                       aMean = NaN;
            
                                    end
            
                                iNaNx = find(~isfinite(vxabs(s1 : s2))); % Find the NaN elements of the x-velocity 
                                                                         % within the times s1 and s2
                                
                                pctInvalidx = (length(iNaNx)/(s2 - s1 + 1)) * 100;    % Compute the percentage of the NaN 
                                                                                      % values of the x-velocity within the times 
                                                                                      % s1 and s2.
            
                                iVx = find(isfinite(vxabs(s1:s2)));  % Find the indexes of the finite elements of the signal 
                                                                     % within the times s1 and s2.
                                iVx = s1 + iVx - 1;  % Find the indexes of the finite elements of the x-velocity 
                                                     % within the times s1 and s2 with respect to the entire 
                                                     % length of the gaze signal          
                                % Find the mean absolute x-velocity and x-acceleration
                                % of the current saccade. 	       
                                if ~isempty(iVx)
                                     vxMean = mean(vxabs(iVx)); 
                                     axMean = mean(ax1(iVx));             
                                else
                                     vxMean = NaN;
                                     axMean = NaN;
                                     
                                end
            
                                %% Modification on 16 March 2022 - vserchi@gmail.com                                                                   
                                %% Compute the Q_ration                                                                                                                                                            
                                Q_ratio_x = vxPeak / vxMean; % ref: federighi 2011
                               
                                %% End of modification
                               
                                iNaNy = find(~isfinite(vyabs(s1 : s2))); % Find the NaN elements of the y-velocity 
                                                                            % within the times s1 and s2
            
                               pctInvalidy = (length(iNaNy) / (s2 - s1 + 1)) * 100; % Compute the percentage of the NaN 
                                                                                    % values of the y-velocity within the times 
                                                                                    % s1 and s2.                    
                               iVy = find(isfinite(vyabs(s1 : s2))); % Find the indexes of the finite elements of the y-velocity 
                                                                   % within the times s1 and s2.
            
                               iVy = s1 + iVy - 1;	% Find the indexes of the finite elements of the y-velocity 
                                                    % within the times s1 and s2 with respect to the entire 
                                                    % length of the gaze signal        
                               
                               % Find the mean absolute y-velocity and y-acceleration
                               % of the current saccade. 
                               if ~isempty(iVy)
                                   vyMean = mean(vyabs(iVy)); 
                                   ayMean = mean(ay1(iVy));             
            
                               else
                                   vyMean = NaN;
                                   ayMean = NaN;
            
                               end
                               
                               %% Modification on 16 March 2022 - vserchi@gmail.com                                                                   
                               %% Compute the Q_ration                                                                                                                                                            
                               Q_ratio_y = vyPeak / vyMean; % ref: federighi 2011
                               
                               %% End of modification
                                 
                            sRT = (s1-tT+1)* acqIntvl; % Find the starting time of the saccade         
                            ttP = (iPeak-tT+1)* acqIntvl; % Find the time of the peak of the absolute velocity        
                            ttPx = (ixPeak-tT+1)* acqIntvl; %#ok<NASGU> % Find the time of the peak of the x-velocity  
                            ttPy = (iyPeak-tT+1)* acqIntvl; %#ok<NASGU> % Find the time of the peak of the y-velocity
            
                             %vPeak = vPeak*acqRate/pixPerDegH; % Convert the peak velocity to deg*s   
                             %vMean = vMean*acqRate/pixPerDegH; % Convert the mean velocity to deg*s    
            %                  vPeak = vPeak;   % 
            %                  vMean = vMean;   % 
            %                     
            %                  vxPeak = vxPeak;   % 
            %                  vxMean = vxMean;   % 
            %                     
            %                  vyPeak = vyPeak;   % 
            %                  vyMean = vyMean;   % 
                              
%                              aPeak1 = aPeak1 * (acqRate * acqRate) / (pixPerDegH); % Find the peak of the absolute acceleration between the i1 and i2 deg
%                              axPeak1 = axPeak1 * (acqRate * acqRate) / (pixPerDegH); % Find the peak of the x-acceleration between the i1 and i2 deg
%                              ayPeak1 = ayPeak1 * (acqRate * acqRate) / (pixPerDegV); % Find the peak of the x-acceleration between the i1 and i2 deg
            
%                              aMean = aMean * (acqRate * acqRate) / (pixPerDegH);  % Convert the mean acceleration to deg*s^2 
%                              axMean = axMean * (acqRate * acqRate) / (pixPerDegH); % Convert the x mean acceleration to deg*s^2  
%                              ayMean = ayMean * (acqRate * acqRate) / (pixPerDegV);  % Convert the y mean acceleration to deg*s^2
                                
                             dSac = vMean * (ts2 - ts1) / 1000; % Compute the saccadic duration     
                             dSacx = vxMean * (ts2 - ts1) / 1000;  % Compute the x saccadic duration   
                             dSacy = vyMean * (ts2 - ts1) / 1000;  % Compute the y saccadic duration   
                                
                             x1 = trialx(s1);  % Compute the saccade x coordinate corrisponding to the start of the current saccade 
                             x2 = trialx(s2);  % Compute the saccade x coordinate corrisponding to the end of the current saccade 
                             y1 = trialy(s1);  % Compute the saccade y coordinate corrisponding to the start of the current saccade
                             y2 = trialy(s2);  % Compute the saccade y coordinate corrisponding to the end of the current saccade 
                             distx = (x2 - x1) / pixPerDegH;  % Compute the x saccadic distance (Pitagora)              
                             disty = (y2 - y1) / pixPerDegV; % Compute the y saccadic distance (Pitagora)
                             distSac = sqrt(distx ^ 2 + disty ^ 2); % Compute the absolute saccadic distance (Pitagora)
                                    
                             distSacx = abs(x2 - x1) / pixPerDegH;  % Convert the x saccadic distance to degree of visual angle (Pitagora)   
                             distSacy = abs(y2 - y1) / pixPerDegV;  % Convert the y saccadic distance to degree of visual angle (Pitagora)   
            
                            % Compute the distance between the minimum and the
                            % miminum value and the starting coordinates of the
                            % saccade. (Same steps as the previos ones)
                              iNaNtx = find(~isfinite(trialx(s1:s2)));
                              pctInvalidtx=(length(iNaNtx)/(s2-s1+1))*100; 
                                
                              iVtx = find(isfinite(trialx(s1:s2)));
                              iVtx = s1 + iVtx - 1;	       
                              if ~isempty(iVtx)
                                  distxPeakMax = max(trialx(iVtx));        
                                  distxPeakMin = min(trialx(iVtx)); 
                                  distxPeakRifMax = abs(x1-distxPeakMax)/pixPerDegH;
                                  distxPeakRifMin = abs(x1-distxPeakMin)/pixPerDegH;
                                  idistxPeakMax = s1 + max(find(trialx(s1:s2) == distxPeakMax)) - 1;   %#ok<MXFND>
                                  idistxPeakMin = s1 + max(find(trialx(s1:s2) == distxPeakMin)) - 1;   %#ok<MXFND>
                                  
                              else
                                  distxPeakMax = NaN; %#ok<NASGU>
                                  distxPeakMax = NaN; %#ok<NASGU>
                                  distxPeakRifMax = NaN; 
                                  distxPeakRifMin = NaN; 
                                  idistxPeakMax = NaN;
                                  idistxPeakMin = NaN;
                                  
                              end
                                             
                              iNaNty = find(~isfinite(trialy(s1:s2)));
                              pctInvalidty=(length(iNaNty)/(s2-s1+1))*100; 
                                
                              iVty = find(isfinite(trialy(s1:s2)));
                              iVty = s1 + iVty - 1;	       
                              if ~isempty(iVty)
                                 distyPeakMax = max(trialy(iVty));       
                                 distyPeakMin = min(trialy(iVty));       
                                 distyPeakRifMax = abs(y1-distyPeakMax)/pixPerDegV;
                                 distyPeakRifMin = abs(y1-distyPeakMin)/pixPerDegV;
                                 idistyPeakMax = s1 + max(find(trialy(s1:s2) == distyPeakMax)) - 1;   %#ok<MXFND>
                                 idistyPeakMin = s1 + max(find(trialy(s1:s2) == distyPeakMin)) - 1;   %#ok<MXFND>
                              
                              else
                                 distyPeakMax = NaN; %#ok<NASGU>
                                 distyPeakMin = NaN; %#ok<NASGU>
                                 distyPeakRifMax = NaN; 
                                 distyPeakRifMin = NaN; 
                                 idistyPeakMax = NaN;  
                                 idistyPeakMin = NaN;
                              
                              end
                                                  
                              durat = (ts2 - ts1); % Get the saccade duration
                              scnt = scnt + 1; % Increment the saccade counter
                                                            
                              C = {i, scnt, s1, s2, vPeak, vMean, ... %6
                                   sRT, ttP, dSac, distSac, distSacx, distSacy, ... %12
                                   pctInvalid, durat, dSacx, dSacy, vxPeak, vxMean, ... %18
                                   vyPeak, vyMean, aMean, pctInvalidx, pctInvalidy, ... %23
                                   pctInvalidtx, pctInvalidty, distxPeakRifMax, ... %26
                                   distxPeakRifMin, distyPeakRifMax, distyPeakRifMin, ... %29
                                   idistxPeakMax, idistxPeakMin, idistyPeakMax, ... %32
                                   idistyPeakMin, t1, t2, aPeak1(1), aPeak1(2), aMean, ...
                                   axPeak1(1), axPeak1(2), axMean, ... %41 (aPeak1 and axPeak1 are couples)
                                   ayPeak1(1), ayPeak1(2), ayMean, app.experiment.datarecorded(s1, 4), ...
                                   skewness_x, skewness_y, Q_ratio_x, Q_ratio_y, ... %49
                                   abruptness_x_acc, abruptness_x_dec, abruptness_y_acc, abruptness_y_dec};
                              
                              T_temp = cell2table(C, 'VariableNames', {'i', 'scnt', 's1', 's2', 'vPeak', 'vMean', ... %6
                                   'sRT', 'ttP', 'dSac', 'distSac', 'distSacx', 'distSacy', ... %12
                                   'pctInvalid', 'durat', 'dSacx', 'dSacy', 'vxPeak', 'vxMean', ... %18
                                   'vyPeak', 'vyMean', 'aMean_1', 'pctInvalidx', 'pctInvalidy', ... %23
                                   'pctInvalidtx', 'pctInvalidty', 'distxPeakRifMax', ... %26
                                   'distxPeakRifMin', 'distyPeakRifMax', 'distyPeakRifMin', ... %29
                                   'idistxPeakMax', 'idistxPeakMin', 'idistyPeakMax', ... %32
                                   'idistyPeakMin', 't1', 't2', 'aPeak1_1', 'aPeak1_2', 'aMean_2', ...
                                   'axPeak1_1', 'axPeak1_2', 'axMean', ... %41 (aPeak1 and axPeak1 are couples)
                                   'ayPeak1_1', 'ayPeak1_2', 'ayMean', 'col4', ...
                                   'skewness_x', 'skewness_y', 'Q_ratio_x', 'Q_ratio_y', ... %49
                                   'abruptness_x_acc', 'abruptness_x_dec', 'abruptness_y_acc', 'abruptness_y_dec'});
                                 
                              saccadeList = [saccadeList; T_temp];
                              
%                               saccadeList = [saccadeList; i scnt s1 s2 vPeak vMean ... %6
%                                              sRT ttP dSac distSac distSacx distSacy ... %12
%                                              pctInvalid durat dSacx dSacy vxPeak vxMean ... %18
%                                              vyPeak vyMean aMean pctInvalidx pctInvalidy ... %23
%                                              pctInvalidtx pctInvalidty distxPeakRifMax ... %26
%                                              distxPeakRifMin distyPeakRifMax distyPeakRifMin ... %29
%                                              idistxPeakMax idistxPeakMin idistyPeakMax ... %32
%                                              idistyPeakMin t1 t2 aPeak1 aMean axPeak1 axMean ... %41 (aPeak1 and axPeak1 are couples)
%                                              ayPeak1 ayMean app.experiment.datarecorded(s1, 4)]; 
% %                               ... %45 (ayPeak1 is a couple)
% %                                              skewness_x skewness_y Q_ratio_x Q_ratio_y ... %49
% %                                              abruptness_x abruptness_x]; %51
                                
                              k = min(find(iCrit > s2)); %#ok<MXFND> % Get the next index for the saccade reaserch 
                                                                     % (next index of the vector iCrit different from zero).
                            else
                               k = k + 1; %k = min(find(iCrit > s2));    % If the duration of the signal within the starting 
                                             % and ending indexes of the current saccade is less 
                                             % than the duration threshold, skip the current saccade 
                                             % because it is not a asaccade.          
                            end
                            
                           else
                               k = k + 1; % If there are not at least three consecutive elements belonging 
                                          % to the current saccades is discarded.
                           end  
                        end  
                    end  
                end   
        
            end   
    end
    
    if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1
%                 saccadeList = []; % Initialize the variable saccadeList to an empty array
          saccadeList = cell2table(cell(0, 53), 'VariableNames', {'i', 'scnt', 's1', 's2', 'vPeak', 'vMean', ... %6
                           'sRT', 'ttP', 'dSac', 'distSac', 'distSacx', 'distSacy', ... %12
                           'pctInvalid', 'durat', 'dSacx', 'dSacy', 'vxPeak', 'vxMean', ... %18
                           'vyPeak', 'vyMean', 'aMean_1', 'pctInvalidx', 'pctInvalidy', ... %23
                           'pctInvalidtx', 'pctInvalidty', 'distxPeakRifMax', ... %26
                           'distxPeakRifMin', 'distyPeakRifMax', 'distyPeakRifMin', ... %29
                           'idistxPeakMax', 'idistxPeakMin', 'idistyPeakMax', ... %32
                           'idistyPeakMin', 't1', 't2', 'aPeak1_1', 'aPeak1_2', 'aMean_2', ...
                           'axPeak1_1', 'axPeak1_2', 'axMean', ... %41 (aPeak1 and axPeak1 are couples)
                           'ayPeak1_1', 'ayPeak1_2', 'ayMean', 'col4', ...
                           'skewness_x', 'skewness_y', 'Q_ratio_x', 'Q_ratio_y', ... %49
                           'abruptness_x_acc', 'abruptness_x_dec', 'abruptness_y_acc', 'abruptness_y_dec'});
                         
                                 
            %                               saccadeList = [saccadeList; T_temp];
            % If the variable index is empty 
            if isempty(index)
                index = [1 size(data, 1)];
            
            end
            
            isFixROI = ~isempty(ROI);   % Assign the logic not of the isempty(ROI) to the variable isFixROI 
            vCrit = saccadeVelThresh;   % Assign to the variable vCrit the value of the threshold for the saccades detection
            trials = size(index, 1);    % Initialize the variable trials to the rows number of the variable index  
            fLen = round(saccadeMinFixDuration / acqIntvl); % Assign the mimimum lenght to detect a fixation to the variable 
                                                            % fLen computed as the ratio between the input threshold and the 
                                                            % acquisition time step
            
            % Get the data of the gaze from the input data variable
            for i = 1 : trials
            %     sprintf('update %f Calculating Trial %i', 100 * i / trials, i) % Print out he number of the trial being processed
                t1 = index(i, 1); % Assign to the variable t1 the number of rows of the input matrix data
                t2 = index(i, 2); % Assign to the variable t2 the number of columns of the input matrix data
                trialx = []; % Initialize to an empty vector the variable trialx
                trialy = []; % Initialize to an empty vector the variable trialy
                
                for ii = t1 : t2
                    
                 % Check if the variable data is a structure. If so, the gaze data 
                    % is stored in the rows of tha variable data corresponding to the 
                    % ii-th trial and to its subfields x and y.If not, it means that
                    % the variable data stores the values relative to only one trial
                    % and the x- and y-coordinates of the gaze are stored in the first
                    % and second columns of the input matrix data. 
                    if (isstruct(data(ii)))
                        trialx = [trialx; data(ii).x]; %#ok<AGROW>
                        trialy = [trialy; data(ii).y]; %#ok<AGROW>
                    
                    else
                        trialx = [trialx; data(ii,1)]; %#ok<AGROW>
                        trialy = [trialy; data(ii,2)]; %#ok<AGROW>
                        
                    end
                end
                
                tT = 1; % Initialize the variable tT to the value 1
               
                if isFixROI
                    g1 = tT;
                    g2 = tT + fLen - 1;         
                                              
                    if g2 > t2 
                        g2 = t2; 
                    
                    end        
                    
                    g = find(isfinite(trialx(g1 : g2)));  
                    inROI = is_in_ROI([trialx(g) trialy(g)], ROI);
                    
                else
                    inROI = 1;
                    
                end
                
                
                if inROI
                    
                    %  --------------------------------------------------------         
                    %  Find the absolute velocity vector (ILAB pixels/sample),
                    %  ALGORITMO a 1 punto
                    %  --------------------------------------------------------         
            %         vx = [0; diff(trialx)];
            %         vy = [0; diff(trialy)];
            %         ax = [0; diff(vx)];
            %         ay = [0; diff(vy)];
            %        
            %         vabs = sqrt(vx.^2 + vy.^2);    %pixel
            %         aabs = sqrt(ax.^2 + ay.^2);    %pixel
            % 
            %
            %      % oppure, calcolo in degree, consderando assi orizzontale e verticale diversi: 
            %         vxt=(vx/pixPerDegH)*acqRate        %degree
            %         vyt=(vy/pixPerDegV)*acqRate        %degree
            %         
            %         vabs = sqrt(vxt.^2 + vyt.^2);      %degree
            %         aabs = sqrt(ax.^2 + ay.^2);        %pixel
            %         
                   
                    a = [0.8024; -0.2022; 0.03904; -0.003732]; % Define the parameters for the 4 points velocity computation
                    vx = [0; diff(trialx)]; % Compute a 1-point velocity along the x-coordinate
            % %         vxrif = vx;
                    vxs = zeros(-5 + t2 - 4, 1); %%[] % Initialize to an empty vector the variable vxs
                    
                    % Computation of the 5-points x-velocity
                    for indDatox = 5 : t2 - 4 % Make a loop over the rows 
                         vx(indDatox, 1) = 0;
                         
                         for nx = 1 : 4        
                              vxs(indDatox, 1) = a(nx, 1) * (trialx(indDatox + nx) - trialx(indDatox - nx));
                              vx(indDatox, 1) = vx(indDatox, 1) + vxs(indDatox, 1);
                         
                         end
                    end
                    
                    vy = [0; diff(trialy)]; % Compute a 1-point velocity along the x-coordinate
            % %         vyrif = vy;
                    vys = zeros(-5 + t2 - 4, 1); %%[] % Initialize to an empty vector the variable vys
                    
                    % Computation of the 5-points y-velocity
                    for indDatoy = 5 : t2 - 4
                         vy(indDatoy, 1) = 0;
            
                         for ny = 1 : 4    
                                  vys(indDatoy, 1) = a(ny, 1) * (trialy(indDatoy + ny) - trialy(indDatoy - ny));
                                  vy(indDatoy, 1) = vy(indDatoy, 1)+vys(indDatoy, 1);
            
                         end
                    end
                                   
                    vx = filter_butterworth_vel(vx);  % Filter the x-velocity vector through a butterworth filter
                    vy = filter_butterworth_vel(vy);  % Filter the y-velocity vector through a butterworth filter
                  
                    vx = [vx(2 + 1 : length(vx), :); zeros(2, 1)]; % Cut the first two samples of the variable vx
                    vy = [vy(2 + 1 : length(vy), :); zeros(2, 1)]; % Cut the first two samples of the variable vy
                            
                    ax = [0; diff(vx)]; % Assign to the variable ax the acceleration of the signal along the x direction
                    ay = [0; diff(vy)];    
             % Assign to the variable ay the acceleration of the signal along the y direction
                      
            %        vabs = sqrt(vx.^2 + vy.^2);    %pixel
            %        aabs = sqrt(ax.^2 + ay.^2);    %pixel
                     
            %       %oppure   
            

                    vx = (vx * acqRate) / pixPerDegH; % Convert the vx from px to degree of visual angles
                    vy = (vy * acqRate) / pixPerDegV; % Convert the vy from px to degree of visual angles
                   
                    ax1 = (ax * acqRate * acqRate) / pixPerDegH; %%MOd
                    ay1 = (ay * acqRate * acqRate) / pixPerDegV; 
                    
                    vabs = sqrt(vx .^ 2 + vy .^ 2); % Compute the absolute velocity of the signal    %degree
                    aabs = sqrt(ax .^ 2 + ay .^ 2); % Compute the absolute acceleration of the signal    %pixel
                    aabs1 = sqrt(ax1 .^ 2 + ay1 .^ 2); % Compute the absolute acceleration of the signal %degree
                    
                    vxabs = sqrt(vx .^ 2); % Compute the absolute value of the x-velocity      %degree     
                    vyabs = sqrt(vy .^ 2); % Compute the absolute value of the y-velocity      %degree     
            
                    axabs = sqrt(ax .^ 2); %#ok<NASGU> % Compute the absolute value of the x-acceleration      %pixel     
                    ayabs = sqrt(ay .^ 2); %#ok<NASGU> % Compute the absolute value of the y-acceleration      %pixel    
                 
                    axabs1 = sqrt(ax1 .^ 2); %#ok<NASGU> % Compute the absolute value of the x-acceleration      %degree     
                    ayabs1 = sqrt(ay1 .^ 2); %#ok<NASGU> % Compute the absolute value of the y-acceleration      %degree    
             
            %         Cutoff = vCrit;  %#ok<NASGU> % solo per plot
                    vCritx = vCrit; % Assign to the variable vCritx the critical value 
                                    % of velocity used to detect a saccade
                                    
                    vCrity = vCrit; % Assign to the variable vCrity the critical value 
                                    % of velocity used to detect a saccade
            %         vxthreshold = [];
            %         vythreshold = [];
                     
                    vxthreshold = filter_threshold_vel(vx,...
                                  vCritx); % Call for the x-velocity threshold filter. Return a vector with null 
                                           % elements where the velocity of the signal is not within the range 
                                           % specified (vCritx). 
                              
                    vythreshold = filter_threshold_vel(vy,...
                                  vCrity); % Call for the y-velocity threshold filter. Return a vector with null 
                                           % elements where the velocity of the signal is not within the range 
                                           % specified (vCrity). 
                  
                    mvxthreshold = sqrt(vxthreshold .^ 2); %#ok<NASGU> % Normalize the velocity threshold vector
                    mvythreshold = sqrt(vythreshold .^ 2); %#ok<NASGU> % Normalize the velocity threshold vector
                    
                    % -----------------------------------------------------------------
            %         % ------------------- plotting during debugging of vabs velocity ---------------------
            %                figure;
            %                hold on;
            %                vRange = acqIntvl*(1:length(vabs));        % range of vabs in ms.
            %                plot(vRange/1000, vabs, 'k');              % plot in pixel per secondi
            %                hold on;
            %                plot(acqIntvl*[1 length(vabs)]./1000, [ vCrit vCrit], 'b--');
            %                drawnow;
            %                pause(1);
            %         % ------------------- plotting during debugging ---------------------
            %         
            %         % ------------------- plotting during debugging of vx velocity ---------------------
            %                figure;
            %                hold on;
            %                vRange = acqIntvl*(1:length(vx));        % range of vabs in ms.
            %                plot(vRange/1000, vx, 'k');              % plot in pixel per secondi
            %                hold on;
            %                plot(acqIntvl*[1 length(vx)]./1000, [ vCrit vCrit], 'b--');
            %                drawnow;
            %                pause(1);
            %         % ------------------- plotting during debugging
            %         
                    % ------------------- plotting during debugging of vy velocity ---------------------
            %                figure;
            %                hold on;
            %                vRange = acqIntvl*(1:length(vy));        % range of vabs in ms.
            %                plot(vRange/1000, vy, 'k');              % plot in pixel per secondi
            %                hold on;
            %                plot(acqIntvl*[1 length(vy)]./1000, [ vCrit vCrit], 'b--');
            %                drawnow;
            %                pause(1);
            %         % ------------------- plotting during debugging
            %         
            %         % ------------------- plotting during debugging of vxabs velocity ---------------------
            %                figure;
            %                hold on;
            %                vRange = acqIntvl*(1:length(vxabs));        % range of vabs in ms.
            %                plot(vRange/1000, vxabs, 'k');              % plot in pixel per secondi
            %                hold on;
            %                plot(acqIntvl*[1 length(vxabs)]./1000, [ vCrit vCrit], 'b--');
            %                drawnow;
            %                pause(1);
                    % ------------------- plotting during debugging
                    
            %         % ------------------- plotting during debugging of vyabs velocity ---------------------
            %                figure;
            %                hold on;
            %                vRange = acqIntvl*(1:length(vyabs));        % range of vabs in ms.
            %                plot(vRange/1000, vyabs, 'k');              % plot in pixel per secondi
            %                hold on;
            %                plot(acqIntvl*[1 length(vyabs)]./1000, [ vCrit vCrit], 'b--');
            %                drawnow;
            %                pause(1);
            %         % ------------------- plotting during debugging
            %                  
            %         % ------------------- plotting during debugging of vxthreshold velocity ---------------------
            %                figure;
            %                hold on;
            %                vRange = acqIntvl*(1:length(vxthreshold));        % range of vabs in ms.
            %                plot(vRange/1000, vxthreshold, 'k');              % plot in pixel per secondi
            %                hold on;
            %                plot(acqIntvl*[1 length(vxthreshold)]./1000, [ vCrit vCrit], 'b--');
            %                drawnow;
            %                pause(1);
            %         % ------------------- plotting during debugging
            %         
            %         % ------------------- plotting during debugging of mvxthreshold velocity ---------------------
            %                figure;
            %                hold on;
            %                vRange = acqIntvl*(1:length(mvxthreshold));        % range of vabs in ms.
            %                plot(vRange/1000, mvxthreshold, 'k');              % plot in pixel per secondi
            %                hold on;
            %                plot(acqIntvl*[1 length(mvxthreshold)]./1000, [ vCrit vCrit], 'b--');
            %                drawnow;
            %                pause(1);
            %         % ------------------- plotting during debugging
            %         
                    % ------------------- plotting during debugging of acceleration ---------------------
            %                figure;
            %                hold on;
            %                vRange = acqIntvl*(1:length(aabs));        % range of aabs in ms.
            %                plot(vRange/1000, aabs, 'k');              %plot in pixel per secondi
            %                hold on;
            %                plot(acqIntvl*[1 length(aabs)]./1000, [ 0 0], 'c--');
            %                drawnow;
            %                pause(1);
                    % ------------------- plotting during debugging
                    % ----------------------------------------------------
                    
                    % Find the critical index to identify the saccades
                    iCrit = find(vythreshold ~= 0); % Assign to the variable iCrit the 
                                                    % index of all the elements of vxthreshold 
                                                    % different from zero (saccades
                                                    % instances).
                   
                    % If elements equal to zero were found go on with the following
                    % computation
                    if ~isempty(iCrit) % If there are saccades
                        k = 1;  % Initialize the counter of the following while loop
                        scnt = 0;  % Initialize a counter to take count of the saccades number
                        s2 = 1;   
                        
                        while k <= length(iCrit)-3   
                           
                           % Check if there are three consecutive indexes having a velocity within the threshold
                           if (iCrit(k) + 1 == iCrit(k + 1)) && (iCrit(k + 1) + 1 == iCrit(k + 2)) && (iCrit(k + 2) + 1 == iCrit(k + 3)) %& (iCrit(k+3)+1==iCrit(k+4)) & (iCrit(k+4)+1==iCrit(k+5))& (iCrit(k+5)+1==iCrit(k+6))& (iCrit(k+6)+1==iCrit(k+7)) & (iCrit(k+7)+1==iCrit(k+8))& (iCrit(k+8)+1==iCrit(k+9)) & (iCrit(k+9)+1==iCrit(k+10))    %condition if tre ara 10 index witn velocity > vCrit 
                               i1 = iCrit(k); % Assign to i1 the index of the current saccade                   
                               valuevx = sign(vythreshold(i1)); % Assign to valuevx the sign of the elements at index i1
                               i2c = min(find(vythreshold(i1 : end) == 0)); %#ok<MXFND> % Find the next index with a zero element 
                               i2c2 = min(find(sign(vythreshold(i1 : end)) ~= valuevx)); %#ok<MXFND> % Find the next index with a sign of the velocity opposite 
                                                                                                     % to the velocity of the signal at the index i1
                               i2 = i1 + i2c - 2; % Define the final index where the vxthreshold goes back to zero
                               i2bis = i1 + i2c2 - 2; % Define the final index where the vxthreshold changes in the sign
                               i2 = min(i2, i2bis); % Overwrite i2 with the minimum index between the next change in sign 
                                                    % and the zeros in the vector
                                                    % vxthreshold.
                            
                               i2 = min(i2, length(vabs)); % If the index is longer than the length of the velocity vector, 
                                                           % overwrite it with the last index of th vector vabs.     
                           
                               %i2 = min(i2bis, length(vabs)); basta questo
                               vPeak = max(vabs(i1 : i2)); % Find the peak of the absolute velocity between the i1 and i2
                               
                               vxPeak = max(vxabs(i1 : i2)); % Find the peak of the x-velocity between the i1 and i2 
                               
                               vyPeak = max(vyabs(i1 : i2)); % Find the peak of the y-velocity between the i1 and i2
            
                               aPeak = [min(aabs(i1 : i2)), max(aabs(i1 : i2))]; %#ok<NASGU> % Find the peak of the absolute acceleration between the i1 and i2 px
                               
                               aPeak1 = [min(aabs1(i1 : i2)), max(aabs1(i1 : i2))]; % Find the peak of the absolute acceleration between the i1 and i2 deg
                               
                               axPeak = [min(ax(i1 : i2)), max(ax(i1 : i2))]; %#ok<NASGU> % Find the peak of the x-acceleration between the i1 and i2 px
                               
                               ayPeak = [min(ay(i1 : i2)), max(ay(i1 : i2))]; %#ok<NASGU> % Find the peak of the y-acceleration between the i1 and i2 px
                              
                               axPeak1 = [min(ax1(i1 : i2)), max(ax1(i1 : i2))]; % Find the peak of the x-acceleration between the i1 and i2 deg
                               
                               ayPeak1 = [min(ay1(i1 : i2)), max(ay1(i1 : i2))]; % Find the peak of the y-acceleration between the i1 and i2 deg
              
                               iPeak = i1 + min(find(vabs(i1 : i2) == vPeak)) - 1;   %#ok<MXFND> % Find the index of the peak of the absolute 
                                                                                                 % velocity between the i1 and i2 
                               ixPeak = i1 + min(find(vxabs(i1 : i2) == vxPeak)) - 1; %#ok<MXFND> % Find the index of the peak of the  
                                                                                                  % x-velocity between the i1 and i2
                                                                                                  
                               iyPeak = i1 + min(find(vyabs(i1 : i2) == vyPeak)) - 1; %#ok<MXFND> % Find the index of the peak of the  
                                                                                                  % y-velocity between the i1 and i2      
                            
                                                                                                  
                               %% Modification on 19 April 2022 - vserchi@gmail.com                                                                   
                               %% Find the indexes corresponding to the peak of the acceleration (min and max)
%                                if i1 == 552
%                                    disp('here')
%                                
%                                end
             
%                                [~, aix_max_Peak] = max(ax1_abs(i1 : i2)); % Find the index of the maximum ax acceleration
%                                aix_max_Peak = i1 + aix_max_Peak - 1; %#ok<NASGU> 
%                          
%                                [~, aix_min_Peak] = min(ax1_abs(i1 : i2)); % Find the index of the minumum ax acceleration
%                                aix_min_Peak = i1 + aix_min_Peak - 1; %#ok<NASGU>                                      
%                                                      
%                                [~, aiy_max_Peak] = max(ay1_abs(i1 : i2)); % Find the index of the maximum ay acceleration 
%                                aiy_max_Peak = i1 + aiy_max_Peak - 1; %#ok<NASGU>
%                                                              
%                                [~, aiy_min_Peak] = min(ay1_abs(i1 : i2)); % Find the index of the minumum ay acceleration
%                                aiy_min_Peak = i1 + aiy_min_Peak - 1; %#ok<NASGU> 
                                       
                               skewness_x = (ixPeak - i1) / (i2 - ixPeak);  % ref: Pamela Federighi's PhD thesis
                               abruptness_x_acc = ((vxPeak - vxabs(i1)) / (ixPeak - i1)) / ((vxPeak - vxabs(i1)) * (i2 - i1)/acqRate); % ref: Pamela Federighi's PhD thesis
                               abruptness_x_dec = ((vxPeak - vxabs(i2)) / (i2 - ixPeak)) / ((vxPeak - vxabs(i2)) * (i2 - i1)/acqRate); % ref: Pamela Federighi's PhD thesis

                               skewness_y = (iyPeak - i1) / (i2 - iyPeak); % ref: Pamela Federighi's PhD thesis
                               abruptness_y_acc = ((vyPeak - vyabs(i1)) / (iyPeak - i1)) / ((vyPeak - vyabs(i1)) * (i2 - i1)/acqRate); % ref: Pamela Federighi's PhD thesis
                               abruptness_y_dec = ((vyPeak - vyabs(i2)) / (i2 - iyPeak)) / ((vyPeak - vyabs(i2)) * (i2 - i1)/acqRate); % ref: Pamela Federighi's PhD thesis

                               %% End of modification                                                                  
                               
                               s1 = i1;  % Assign to s1 the index of start of the current saccade (i1) 
                                
                               % Assign to s2 the ending index of the current saccade (i2)
                               if isempty(s2)
                                   s2 = length(vabs);
                                
                               else
                                   s2 = i2;
                                
                               end
                            
            
                               % Compute the time in ms corresponding to the starting
                               % and ending indexes of the current saccades
                               ts1 = s1 * acqIntvl;  % [ms]
                               ts2 = s2 * acqIntvl;  % [ms]
                            
                               %sprintf('ts2,ts1, saccadeMinDuration %i %i %i',ts2,ts1, saccadeMinDuration)
                            
                              % Check if the current saccade is a saccade. Check the
                               % duration of the signal within the starting and ending
                               % indexes of the current saccade.
                               if ( (ts2-ts1) > saccadeMinDuration)
                                   iNaN = find( ~isfinite(vabs(s1 : s2))); % Find the NaN elements of the signal 
                                                                           % within the times s1 and s2.
                                                                           
                                   pctInvalid = (length(iNaN) / (s2 - s1 + 1)) * 100; % Compute the percentage of the NaN 
                                                                                      % values of the vector within the times 
                                                                                      % s1 and s2.
                                
                                   iV = find(isfinite(vabs(s1 : s2))); % Find the indexes of the finite elements of the signal 
                                                                       % within the times s1 and s2.
                                                                       
                                   iV = s1 + iV - 1; % Find the indexes of the finite elements of the signal 
                                                     % within the times s1 and s2 with respect to the entire 
                                                     % length of the gaze signal   
                                   
                                   % Find the mean absolute velocity and acceleration
                                   % of the current saccade.                        
                                   if ~isempty(iV)
                                       vMean = mean(vabs(iV));
                                       aMean = mean(aabs(iV));             
                                    
                                   else
                                       % If no finite elements are found the mean
                                       % absolute velocity and acceleration are set to
                                       % NaN values.
                                       vMean = NaN;
                                       aMean = NaN;
                                       
                                   end
                                   
                                   iNaNx = find( ~isfinite(vxabs(s1 : s2))); % Find the NaN elements of the x-velocity 
                                                                             % within the times s1 and s2
                                                                             
                                   pctInvalidx = (length(iNaNx)/(s2 - s1 + 1)) * 100; % Compute the percentage of the NaN 
                                                                                      % values of the x-velocity within the times 
                                                                                      % s1 and s2.
                                
                            
                                    iVx = find(isfinite(vxabs(s1 : s2))); % Find the indexes of the finite elements of the signal 
                                                                         % within the times s1 and s2.
                                                                         
                                   iVx = s1 + iVx - 1;	 % Find the indexes of the finite elements of the x-velocity 
                                                         % within the times s1 and s2 with respect to the entire 
                                                         % length of the gaze signal      
                            
                                   % Find the mean absolute x-velocity and x-acceleration
                                   % of the current saccade. 
                                   if ~isempty(iVx)
                                       vxMean = mean(vxabs(iVx)); 
                                       axMean = mean(ax1(iVx));            
                                    
                                   else
                                       vxMean = NaN;
                                       axMean = NaN;
                                    
                                   end
                                
                                   %% Modification on 19 April 2022 - vserchi@gmail.com                                                                   
                                   %% Compute the Q_ration                                                                                                                                                            
                                   Q_ratio_x = vxPeak / vxMean; % ref: federighi 2011
                               
                                   %% End of modification
                                
                                   iNaNy = find(~isfinite(vyabs(s1 : s2))); % Find the NaN elements of the y-velocity 
                                                                            % within the times s1 and s2
                                                                          
                                   pctInvalidy = (length(iNaNy) / (s2 - s1 + 1)) * 100; % Compute the percentage of the NaN 
                                                                                        % values of the y-velocity within the times 
                                                                                        % s1 and s2.
                                
                            
                                   iVy = find(isfinite(vyabs(s1 : s2))); % Find the indexes of the finite elements of the y-velocity 
                                                                       % within the times s1 and s2.
                                                                       
                                   iVy = s1 + iVy - 1;	% Find the indexes of the finite elements of the y-velocity 
                                                        % within the times s1 and s2 with respect to the entire 
                                                        % length of the gaze signal        
                                   
                                   % Find the mean absolute y-velocity and y-acceleration
                                   % of the current saccade. 
                                    if ~isempty(iVy)
                                       vyMean = mean(vyabs(iVy)); 
                                       ayMean = mean(ay1(iVy));             
                                    
                                   else
                                       vyMean = NaN;
                                       ayMean = NaN;
                                    
                                   end
            
                                   %% Modification on 19 April 2022 - vserchi@gmail.com                                                                   
                                   %% Compute the Q_ration                                                                                                                                                            
                                   Q_ratio_y = vyPeak / vyMean; % ref: federighi 2011
                                   
                                   %% End of modification
                                   sRT = (s1 - tT + 1) * acqIntvl;   % Find the starting time of the saccade  
                                   ttP = (iPeak - tT + 1) * acqIntvl; % Find the time of the peak of the absolute velocity    
                                   ttPx = (ixPeak - tT + 1) * acqIntvl; %#ok<NASGU> % Find the time of the peak of the x-velocity  
                                   ttPy = (iyPeak - tT + 1) * acqIntvl; %#ok<NASGU> % Find the time of the peak of the y-velocity
            
            %                 % --------------- plotting during debugging ------------------
            %                 %                     
            %                          %disp(sprintf('Trial %d  Saccade st: %.2f end: %.2f dur: %.1f ms vPk = %.1f vAvg = %.1f Inv = %.0f',...
            %                          %i, ts1/1000, ts2/1000, ts2-ts1, vPeak, vMean, pctInvalid));
            %                                                                      
            %                          sRange = (s1:s2) * acqIntvl;
            %                          plot(sRange/1000, vabs(s1:s2), 'r'); %plot in ms
            %                          plot(iPeak*acqIntvl/1000, vabs(iPeak), 'ro');  % vPeak where search started
            %                                                       
            %                          plot([s1 s2]*acqIntvl/1000, [vCutoff vCutoff], 'r:');
            %                          plot(ts1/1000, vCutoff, 'r^');
            %                          plot(ts2/1000, vCutoff, 'r^');
            %                          drawnow
            %                  % --------------- plotting during debugging ----------------
                                
                          
            %                  vPeak = vPeak*acqRate/pixPerDegH; %  convert to deg/s
                             %vMean = vMean*acqRate/pixPerDegH; %  convert to deg/s
                            
            %                  vPeak = vPeak;   % non necessita di convert to deg/s
            %                  vMean = vMean;   % non necessita di convert to deg/s
            %                     
            %                  vxPeak = vxPeak;   % non necessita di convert to deg/s
            %                  vxMean = vxMean;   % non necessita di convert to deg/s
            %                     
            %                  vyPeak = vyPeak;   % non necessita di convert to deg/s
            %                  vyMean = vyMean;   % non necessita di convert to deg/s
            %                     
%                              aPeak1 = aPeak1 * (acqRate * acqRate) / (pixPerDegH); % Find the peak of the absolute acceleration between the i1 and i2 deg
%                              axPeak1 = axPeak1 * (acqRate * acqRate) / (pixPerDegH); % Find the peak of the x-acceleration between the i1 and i2 deg
%                              ayPeak1 = ayPeak1 * (acqRate * acqRate) / (pixPerDegV); % Find the peak of the x-acceleration between the i1 and i2 deg
%             
%                              aMean = aMean * (acqRate * acqRate) / (pixPerDegH);  % Convert the mean acceleration to deg*s^2 
%                              axMean = axMean * (acqRate * acqRate) / (pixPerDegH); % Convert the x mean acceleration to deg*s^2  
%                              ayMean = ayMean * (acqRate * acqRate) / (pixPerDegV);  % Convert the y mean acceleration to deg*s^2
             
                                   dSac = vMean * (ts2 - ts1) / 1000; % Compute the saccadic duration   
                                   dSacx = vxMean * (ts2 - ts1) / 1000; % Compute the saccadic duration     
                                   dSacy = vyMean * (ts2 - ts1) / 1000; % Compute the saccadic duration    
                                
                                   x1 = trialx(s1);  % Compute the saccade x coordinate corrisponding to the start of the current saccade 
                                   x2 = trialx(s2);  % Compute the saccade x coordinate corrisponding to the end of the current saccade 
                                   y1 = trialy(s1);  % Compute the saccade y coordinate corrisponding to the start of the current saccade
                                   y2 = trialy(s2);  % Compute the saccade y coordinate corrisponding to the end of the current saccade 
                             
                                   distx = (x2 - x1) / pixPerDegH; % Compute the x saccadic distance (Pitagora)          
                                   disty = (y2 - y1) / pixPerDegV; % Compute the y saccadic distance (Pitagora)     
                                   distSac = sqrt(distx ^ 2 + disty ^ 2); % Compute the absolute saccadic distance (Pitagora)     
                                   distSacx = abs(x2 - x1) / pixPerDegH;  % Convert the x saccadic distance to degree of visual angle (Pitagora)   
                                   distSacy = abs(y2 - y1) / pixPerDegV;  % Convert the y saccadic distance to degree of visual angle (Pitagora)    
                              
                                   % Compute the distance between the minimum and the
                                   % miminum value and the starting coordinates of the
                                   % saccade. (Same steps as the previos ones)
                                   iNaNtx = find(~isfinite(trialx(s1 : s2)));
                                   pctInvalidtx = (length(iNaNtx) / (s2 - s1 + 1)) * 100; 
                                
                                   iVtx = find(isfinite(trialx(s1 : s2)));
                                   iVtx = s1 + iVtx - 1;	       
                                   
                                   if ~isempty(iVtx)
                                       distxPeakMax = max(trialx(iVtx));  
                                       distxPeakMin = min(trialx(iVtx)); 
                                       distxPeakRifMax = abs(x1 - distxPeakMax) / pixPerDegH;
                                       distxPeakRifMin = abs(x1 - distxPeakMin) / pixPerDegH;
                                       idistxPeakMax = s1 + max(find(trialx(s1 : s2) == distxPeakMax)) - 1;   %#ok<MXFND>
                                       idistxPeakMin = s1 + max(find(trialx(s1 : s2) == distxPeakMin)) - 1;   %#ok<MXFND>
                                    
                                   else
                                       distxPeakMax = NaN; %#ok<NASGU>
                                       distxPeakMax = NaN; %#ok<NASGU>
                                       distxPeakRifMax = NaN;
                                       distxPeakRifMin = NaN; 
                                       idistxPeakMax = NaN;
                                       idistxPeakMin = NaN;
                                                              
                                   end
                              
                                   iNaNty = find(~isfinite(trialy(s1 : s2)));
                                   pctInvalidty = (length(iNaNty) / (s2 - s1 + 1)) * 100; 
                                   iVty = find(isfinite(trialy(s1 : s2)));
                                   iVty = s1 + iVty - 1;      
                                   
                                   
                                   if ~isempty(iVty)
                                       distyPeakMax = max(trialy(iVty));   
                                       distyPeakMin = min(trialy(iVty));     
                                       distyPeakRifMax = abs(y1 - distyPeakMax) / pixPerDegV;
                                       distyPeakRifMin = abs(y1 - distyPeakMin) / pixPerDegV;
                                       idistyPeakMax = s1 + max(find(trialy(s1 : s2) == distyPeakMax)) - 1;   %#ok<MXFND>
                                       idistyPeakMin = s1 + max(find(trialy(s1 : s2) == distyPeakMin)) - 1;   %#ok<MXFND>
            
                                   else
            % % % %                            distyPeakMax = NaN;
            % % % %                            distyPeakMin = NaN;
                                       distyPeakRifMax = NaN; 
                                       distyPeakRifMin = NaN; 
                                       idistyPeakMax = NaN; 
                                       idistyPeakMin = NaN;
                                       
                                   end
                                                  
                                   durat =(ts2 - ts1); % Get the saccade duration
                                    
                              %disp(sprintf('Trial %d dist %.1f %.1f\n', i, dSac, dist));
                                
                                   scnt = scnt + 1;    % Increment the saccade counter
                                   
                                  C = {i, scnt, s1, s2, vPeak, vMean, ... %6
                                       sRT, ttP, dSac, distSac, distSacx, distSacy, ... %12
                                       pctInvalid, durat, dSacx, dSacy, vxPeak, vxMean, ... %18
                                       vyPeak, vyMean, aMean, pctInvalidx, pctInvalidy, ... %23
                                       pctInvalidtx, pctInvalidty, distxPeakRifMax, ... %26
                                       distxPeakRifMin, distyPeakRifMax, distyPeakRifMin, ... %29
                                       idistxPeakMax, idistxPeakMin, idistyPeakMax, ... %32
                                       idistyPeakMin, t1, t2, aPeak1(1), aPeak1(2), aMean, ...
                                       axPeak1(1), axPeak1(2), axMean, ... %41 (aPeak1 and axPeak1 are couples)
                                       ayPeak1(1), ayPeak1(2), ayMean, app.experiment.datarecorded(s1, 4), ...
                                       skewness_x, skewness_y, Q_ratio_x, Q_ratio_y, ... %49
                                       abruptness_x_acc, abruptness_x_dec, abruptness_y_acc, abruptness_y_dec};
                                  
                                  T_temp = cell2table(C, 'VariableNames', {'i', 'scnt', 's1', 's2', 'vPeak', 'vMean', ... %6
                                       'sRT', 'ttP', 'dSac', 'distSac', 'distSacx', 'distSacy', ... %12
                                       'pctInvalid', 'durat', 'dSacx', 'dSacy', 'vxPeak', 'vxMean', ... %18
                                       'vyPeak', 'vyMean', 'aMean_1', 'pctInvalidx', 'pctInvalidy', ... %23
                                       'pctInvalidtx', 'pctInvalidty', 'distxPeakRifMax', ... %26
                                       'distxPeakRifMin', 'distyPeakRifMax', 'distyPeakRifMin', ... %29
                                       'idistxPeakMax', 'idistxPeakMin', 'idistyPeakMax', ... %32
                                       'idistyPeakMin', 't1', 't2', 'aPeak1_1', 'aPeak1_2', 'aMean_2', ...
                                       'axPeak1_1', 'axPeak1_2', 'axMean', ... %41 (aPeak1 and axPeak1 are couples)
                                       'ayPeak1_1', 'ayPeak1_2', 'ayMean', 'col4', ...
                                       'skewness_x', 'skewness_y', 'Q_ratio_x', 'Q_ratio_y', ... %49
                                       'abruptness_x_acc', 'abruptness_x_dec', 'abruptness_y_acc', 'abruptness_y_dec'});
                                     
                                  saccadeList = [saccadeList; T_temp];
                                   
            %                               saccadeList = [saccadeList; i scnt s1 s2 vPeak vMean ... %6
            %                                              sRT ttP dSac distSac distSacx distSacy ... %12
            %                                              pctInvalid durat dSacx dSacy vxPeak vxMean ... %18
            %                                              vyPeak vyMean aMean pctInvalidx pctInvalidy ... %23
            %                                              pctInvalidtx pctInvalidty distxPeakRifMax ... %26
            %                                              distxPeakRifMin distyPeakRifMax distyPeakRifMin ... %29
            %                                              idistxPeakMax idistxPeakMin idistyPeakMax ... %32
            %                                              idistyPeakMin t1 t2 aPeak1 aMean axPeak1 axMean ... %41 (aPeak1 and axPeak1 are couples)
            %                                              ayPeak1 ayMean app.experiment.datarecorded(s1,4)];
            % %                                              ... %45 (ayPeak1 is a couple)
            % %                                              skewness_x skewness_y Q_ratio_x Q_ratio_y ... %49
            % %                                              abruptness_x abruptness_x]; %51
                                        
                                   k = min(find(iCrit > s2)); %#ok<MXFND> % Get the next index for the saccade reaserch 
                                                              % (next index of the vector iCrit different from zero).
                                  
                               else
                                   k = k + 1; % If the duration of the signal within the starting 
                                              % and ending indexes of the current saccade is less 
                                              % than the duration threshold, skip the current saccade 
                                              % because it is not a asaccade.
                              
                               end         
                           
                           else
                               k = k + 1; % If there are not at least three consecutive elements belonging 
                                          % to the current saccades is discarded.
                           
                           end  
                        end 
                        
                    end 
                    
                end 
            end
    end
    

end


    end  


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectfileButtonPushed(app, event)
            
            % Read the data file (.txt)
            if contains(app.DeviceDropDown.Value, 'Eye') | ...
                    contains(app.DeviceDropDown.Value, 'ASL')
                
                [filename, pathname] = uigetfile ('*txt*', 'Select the file');

            else if  contains(app.DeviceDropDown.Value, 'old')
                [filename, pathname] = uigetfile ('*mat*', 'Select the file');

                end
            end

            [~, ~, ext] = fileparts(filename);
            
            % Print current selected file in the App interface
            app.CurrentFileEditField.Value = [pathname, filename];
            
            update_settings(app);
            
            if contains(app.DeviceDropDown.Value, 'old') == 1
                old_data = load([pathname, filename]);
                              
                % Save informations in a struct
                app.experiment.fname = old_data.data.fname; 
              
                % Depending on the model of the eye-tracker, log file
                % containing raw gaze information may change. For this reason,
                % we specify rules to upload data from the original log file in
                % a standard format
                disp("Reading file, cleaning and pre-processing...");
                app.experiment.settings = old_data.data.settings;
                set(app.ExperimentDropDown, 'Enable', 'on')
                set(app.ExperimentDropDown, 'Value', old_data.data.typeAnalysis);
                app.experiment.datarecorded_raw = old_data.data.datarecorded_raw;
                app.experiment.datarecorded = old_data.data.datarecorded;
                app.experiment.Hz = old_data.data.Hz;
                
                plot_signal(app);
            
                app.experiment.saccadeList = old_data.data.saccadeList;
                app.experiment.deletedSaccades = old_data.data.deletedSaccades;
                app.experiment.saccadeListSelected = old_data.data.saccadeListSelected;
                
            else
                % Print current selected file in the App interface
                app.CurrentFileEditField.Value = [pathname, filename];
                 
                % Save informations in a struct
                app.experiment.fname = filename(1:end-4); 
            
                % Depending on the model of the eye-tracker, log file
                % containing raw gaze information may change. For this reason,
                % we specify rules to upload data from the original log file in
                % a standard format
                disp("Reading file, cleaning and pre-processing...");
                if strcmp(app.DeviceDropDown.Value, 'EyeLink Portable Duo') == 1  
                    % Setup the Import Options and import the data
                    opts = delimitedTextImportOptions("NumVariables", 6);
                    
                    % Specify range and delimiter
                    opts.DataLines = [2, Inf];
                    opts.Delimiter = "\t";
                    
                    % Specify column names and types
                    opts.VariableNames = ["AVERAGE_GAZE_X", "AVERAGE_GAZE_Y", "AVERAGE_PUPIL_SIZE", "TIMESTAMP", "TARGET_X_TARG1", "TARGET_Y_TARG1"];
                    opts.VariableTypes = ["double", "double", "double", "double", "double", "double"];
                    
                    % Specify file level properties
                    opts.ExtraColumnsRule = "ignore";
                    opts.EmptyLineRule = "read";
                    
                    % Specify variable properties
                    opts = setvaropts(opts, ["AVERAGE_GAZE_X", "AVERAGE_GAZE_Y", "AVERAGE_PUPIL_SIZE", "TIMESTAMP", "TARGET_X_TARG1", "TARGET_Y_TARG1"], "DecimalSeparator", ",");
                        
                    % Import the data
                    eyelinktable = readtable([pathname, filename], opts);                 
%                     eyelinktable = eyelinktable(:, ...
%                         {'AVERAGE_GAZE_X','AVERAGE_GAZE_Y', 'AVERAGE_PUPIL_SIZE', ...
%                         'TIMESTAMP', 'TARGET_X_TARG1', 'TARGET_Y_TARG1'});                
                    eyelinkmatrix = table2array(eyelinktable);
%                     eyelinkmatrix(:, 4) = eyelinkmatrix(:, 4) / 100;
                    eyelinkmatrix(isnan(eyelinkmatrix)) = 0;
                    app.experiment.datarecorded_raw = eyelinkmatrix;
                    app.experiment.datarecorded_raw = [app.experiment.datarecorded_raw, zeros(size(app.experiment.datarecorded_raw, 1), 1)];
                    app.experiment.datarecorded_raw = app.risampledata(app.experiment.datarecorded_raw, 240);
                    app.experiment.Hz = 240;
                    
                    else if contains(app.DeviceDropDown.Value, 'ASL') == 1
                        asltable = readtable([pathname, filename]);                 
                        aslmatrix = table2array(asltable);
                        aslmatrix(isnan(aslmatrix)) = 0;
                        app.experiment.datarecorded_raw = aslmatrix;
                        app.experiment.datarecorded_raw = [app.experiment.datarecorded_raw, zeros(size(app.experiment.datarecorded_raw, 1), 1)];
                        app.experiment.datarecorded_raw = app.risampledata(app.experiment.datarecorded_raw, 240);                                      
                        app.experiment.Hz = 240;
                    
                    end
                end
                    
                app.experiment.datarecorded = deblink_interp(app);
                app.experiment.datarecorded(:, 4) = app.experiment.datarecorded(:, 4) - app.experiment.datarecorded(1, 4);
                           
                plot_signal(app);
            
                % saccade detection
                saccade_report(app);
                
                % create vector of manually deleted saccades
                app.experiment.deletedSaccades = [];
                
            end
            
            toggleGUI(app)
                 
            % then, for each saccade, compute some needed statistics
            add_column_type(app);
            
            add_column_gain(app);
            add_column_accuracy(app);
            add_column_latency(app);
            
            add_column_gap_id(app);
            % finally, update table in the gui
            updatetable(app);
            
%             app.UITable.SelectionType = 'row';
            app.experiment.add_sacc_index = [];
            
        end

        % Value changed function: ExperimentDropDown
        function ExperimentDropDownValueChanged(app, event)
            set(app.SelectFileButton, 'Enable', 'on')
            set(app.CurrentFileEditField, 'Enable', 'on')
            
            if ~strcmp(app.CurrentFileEditField.Value, '')
                if isa(app.experiment, 'struct')
                    updatetable(app);
                    plot_signal(app);
                end
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
%             app.experiment.datarecorded = deblink_interp(app);
            
%             plot_signal(app);
            update_settings(app)
                     
            saccade_report(app);
            if ~isempty(app.experiment.saccadeList)
                add_column_type(app);
            
                add_column_gain(app);
                add_column_accuracy(app);
                add_column_latency(app);
                add_column_gap_id(app);
            
            end
            updatetable(app);
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            web('http://neurosense.wp.unisi.it/');
        end

        % Value changed function: MinAmplitudeSpinner
        function MinAmplitudeSpinnerValueChanged(app, event)
            updatetable(app);
        end

        % Value changed function: MaxAmplitudeSpinner
        function MaxAmplitudeSpinnerValueChanged(app, event)
            value = app.MaxAmplitudeSpinner.Value;
            updatetable(app);
        end

        % Value changed function: MinDurationSpinner
        function MinDurationSpinnerValueChanged(app, event)
            value = app.MinDurationSpinner.Value;
            updatetable(app);
        end

        % Value changed function: MaxDurationSpinner
        function MaxDurationSpinnerValueChanged(app, event)
            value = app.MaxDurationSpinner.Value;
            updatetable(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            removeStyle(app.UITable) % matlab 2019b
            indices = event.Indices;
            selectedRow = unique(indices(:, 1));
            
            s = uistyle("BackgroundColor", '#d9efff'); % matlab 2019b
            addStyle(app.UITable, s, "row", selectedRow) % matlab 2019b
            
            app.experiment.selectedRow = selectedRow;
            
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                if contains(app.DeviceDropDown.Value, 'ASL') == 1
                    app.UIAxes.YLim = [-25 25];
                    
                else
                    app.UIAxes.YLim = [-25 25];
                
                end        
            end
            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1
                app.UIAxes.YLim = [-10 +10];
                
            end
            
            % set axis limits to zoom on the selected saccade
            %list_time_start = table2array(app.UITable.Data(selectedRow, 4));
            list_time_start = app.UITable.Data{selectedRow, {'Time (start)'}};
%             list_time_end = list_time_start + table2array(app.UITable.Data(selectedRow, 5));  
            list_time_end = list_time_start + app.UITable.Data{selectedRow, {'Duration'}};  

            timeStart = min(list_time_start);
            timeEnd = max(list_time_end);
            
            app.UIAxes.XLim = [timeStart-500 timeEnd+500];
            
            % color the selected saccade differently

            plot_signal(app);

            saccade_color = [0 1 0];
            timestamp = app.experiment.datarecorded(:, 4);
            
            
            for ind = 1 : length(list_time_start)
                idT1 = find(timestamp == list_time_start(ind));                
                idT2 = find(timestamp > list_time_end(ind), 1, 'first');
                
                if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                                
                    % Plot Gaze X
                    plot( ...
                        app.UIAxes, ...
                        timestamp(idT1:idT2), ...
                        (app.experiment.datarecorded(idT1:idT2, 1) - app.experiment.settings.HorScreenRes/2) ./ app.experiment.settings.pixPerDegH, ...
                        'Color', saccade_color);
                    
                end
                
                if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1
                    
                    % Plot Gaze Y
                    plot( ...
                        app.UIAxes, ...
                        timestamp(idT1:idT2), ...
                        (app.experiment.datarecorded(idT1:idT2, 2) - app.experiment.settings.VerScreenRes/2) ./ app.experiment.settings.pixPerDegV, ...
                        'Color', saccade_color);
                    
                end
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            removeStyle(app.UITable) % matlab 2019b
            
            try
                selecteRow = app.experiment.selectedRow;
            
                if length(selecteRow) > 1
                    selectedSample = app.UITable.Data{selecteRow, {'Saccade ID'}}; %app.UITable.Data(selecteRow, 2));
                    app.experiment.deletedSaccades = [app.experiment.deletedSaccades; selectedSample];
                                   
                else    
                    selectedSample = app.UITable.Data{selecteRow, {'Saccade ID'}}; %table2array(app.UITable.Data(selecteRow,1));
                    app.experiment.deletedSaccades = [app.experiment.deletedSaccades; selectedSample];
                    
                end 
    
                updatetable(app);
                plot_signal(app)
            
            catch
                disp('There are no saccades associated to this file')
                
            end
        end

        % Value changed function: SaccTypeDropDown
        function SaccTypeDropDownValueChanged(app, event)
            value = app.SaccTypeDropDown.Value;
            updatetable(app);
            
        end

        % Value changed function: SavereportButton
        function SavereportButtonValueChanged(app, event)
            value = app.SavereportButton.Value;
            [file, path] = uiputfile('*.xlsx');
            
            if ~isempty(app.UITable.Data)
                app.UITable.Data.Properties.VariableNames = app.UITable.ColumnName;
    
            else
                app.UITable.Data = array2table(zeros(0, size(app.UITable.ColumnName, 1)));
                app.UITable.Data.Properties.VariableNames = app.UITable.ColumnName;

            end
    
            writetable(app.UITable.Data, strcat(path, file));
           
            if app.PhaseplaneCheckBox.Value == 1
                app.experiment.phase_plane = app.compute_phase_plane(file(1:end-5));             
                
            end
            
            if app.matCheckBox.Value
                app.experiment.typeAnalysis = app.ExperimentDropDown.Value;
                data = app.experiment;
                save(['',  file(1:end-5), '.mat'], 'data')
            
            end
            
            if app.figCheckBox.Value
                % Matlab 2019b
                newfigure = figure;
                ax = axes;
                copyobj(app.UIAxes.Children, ax)
                saveas(newfigure, ['',  file(1:end-5), '.fig'])
            
            end
            
            if app.MainsequenceCheckBox.Value
                % Matlab 2019b
                if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                      distColumn = 'distSacx';
                      
                end   
                
                if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1            
                      distColumn = 'distSacy';
                      
                end
                    
                
                newfigure = figure;
                ax = axes;
                subplot(1,2,1)
                hold on
                grid on
                box on
                plot(app.experiment.saccadeListSelected{:, { eval(['''', distColumn, ''''])}}, ...
                    app.experiment.saccadeListSelected{:, {'vPeak'}}, 'b*')
                ylabel('Peak Velocity')
                xlabel('Amplitude')
                
                subplot(1,2,2)
                hold on
                grid on
                box on
                plot(app.experiment.saccadeListSelected{:, { eval(['''', distColumn, ''''])}}, ...
                    app.experiment.saccadeListSelected{:, {'durat'}}, 'b*')
                ylabel('Duration')
                xlabel('Amplitude')
                saveas(newfigure, ['',  file(1:end-5), '_MS.fig'])
            
            end
            
           
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.experiment.deletedSaccades = app.experiment.deletedSaccades(1:end-1);
            updatetable(app);
            
        end

        % Callback function: UIAxes
        function UIAxesButtonDown(app, event)

            A = get(app.UIAxes,'CurrentPoint');
            indexToFind = A(1, 1);
            
            [~, add_sacc_index] = min(abs(app.experiment.saccadeList{:, {'s1'}} *  (1 / app.experiment.Hz * 1000) - indexToFind));
            app.experiment.add_sacc_index = add_sacc_index;
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if ~isempty(app.experiment.saccadeList)
                if ~isempty(app.experiment.add_sacc_index)
                    if isempty(find(app.experiment.saccadeListSelected{:, {'s1'}} == app.experiment.saccadeList{app.experiment.add_sacc_index, {'s1'}})) %app.experiment.saccadeList(app.experiment.add_sacc_index, 3)))
                        app.experiment.saccadeListSelected = [app.experiment.saccadeListSelected; 
                            app.experiment.saccadeList(app.experiment.add_sacc_index, :)];
                        
                        app.experiment.saccadeListSelected = sortrows(app.experiment.saccadeListSelected, 3);
                    
                    end
                    
                    app.experiment.deletedSaccades(app.experiment.deletedSaccades == app.experiment.add_sacc_index, :) = [];
                    
                    app.experiment.deletedSaccades = app.experiment.deletedSaccades(find(app.experiment.deletedSaccades));
                    
                    updatetable(app)
                end
            end
        end

        % Value changed function: RemovereturnsaccadesCheckBox
        function RemovereturnsaccadesCheckBoxValueChanged(app, event)
            removeStyle(app.UITable) % matlab 2019b
            value = app.RemovereturnsaccadesCheckBox.Value;
            
            if ~isempty(app.experiment.saccadeList)
                if value == 1
                    app.RemovereturnsaccadesCheckBox.Text = 'Add return saccades';
                    app.experiment.deletedSaccades = ...
                        [app.experiment.deletedSaccades; ...
                        app.experiment.saccadeList{app.experiment.saccadeList{:, {'saccade_group'}} == 2, {'scnt'}}]; %app.experiment.saccadeList(:, 53) == 2, 2);
                    
                    updatetable(app);
                    
                    plot_signal(app)
                    
                else
                    app.RemovereturnsaccadesCheckBox.Text = 'Remove return saccades';
                    return_saccade = app.experiment.saccadeList{app.experiment.saccadeList{:, ...
                        {'saccade_group'}} == 2, {'scnt'}}; %app.experiment.saccadeList(app.experiment.saccadeList(:, 53) == 2, 2);
                    app.experiment.saccadeListSelected = [app.experiment.saccadeListSelected; app.experiment.saccadeList(return_saccade, :)];
                    [~, idx] = unique(app.experiment.saccadeListSelected{:, {'scnt'}}, 'rows', 'first');
                    app.experiment.saccadeListSelected = app.experiment.saccadeListSelected(idx, :);
                    
                    for ind = 1 : length(return_saccade)
                        if find(app.experiment.deletedSaccades == return_saccade(ind))
                            app.experiment.deletedSaccades(app.experiment.deletedSaccades == return_saccade(ind)) = [];
    
                        end
                    end
                    
                    app.experiment.deletedSaccades = app.experiment.deletedSaccades(find(app.experiment.deletedSaccades));
                    
                    updatetable(app);
                    
                    plot_signal(app)
                    
                end
            end
        end

        % Value changed function: DeviceDropDown
        function DeviceDropDownValueChanged(app, event)
           
            if ~strcmp(app.CurrentFileEditField.Value, "")
                app.ResetButtonPushed()
            
            else
                set(app.ExperimentDropDown, 'Enable', 'off')
            
            end
            
            value = app.DeviceDropDown.Value;
            
            if contains(value, 'Eye') | ...
               contains(value, 'ASL')
                set(app.ExperimentDropDown, 'Enable', 'on');
                                
            else if contains(value, 'old')
                set(app.SelectFileButton, 'Enable', 'on')
                set(app.CurrentFileEditField, 'Enable', 'on')
                
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            toggleGUI(app)
            
            set(app.SelectFileButton, 'Enable', 'off')
            set(app.CurrentFileEditField, 'Enable', 'off')
            
            cla(app.UIAxes)            
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Gaze')            
            app.CurrentFileEditField.Value = "";
            
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
%                 distColumn = 11;  
                  distColumn = 'distSacx';
                  
            end   
            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1            
%                 distColumn = 12;
                  distColumn = 'distSacy';
                  
            end
            
            set(app.DeviceDropDown, 'Value', 'Select a value')  
            set(app.ExperimentDropDown, 'Value', 'Select Experiment Type')  
                                                        
            app.experiment.datarecorded = [];
            app.experiment.Hz = [];
            
            app.experiment.deletedSaccades = [];
                    
            if ~isempty(app.experiment.saccadeListSelected)
                app.UITable.Data = array2table( ...
                    app.experiment.saccadeListSelected{[], {'gap_id', ...
                    'scnt', 's1', 'col4', 'durat', ...
                    eval(['''', distColumn, '''']), 'vPeak', 'vMean', ...
                    'saccade_type', 'saccade_group',  'gain', ...
                    'accuracy', 'latency', 'axPeak1_1', ...
                     'axPeak1_2', 'skewness_x', 'Q_ratio_x', ...
                     'abruptness_x_acc', 'abruptness_x_dec'}}, ...
                    "VariableNames", {'Gap ID'; 'Saccade ID'; ...
                    'Sample ID'; 'Time (start)'; 'Duration'; ...
                        'Amplitude (y)'; 'Peak velocity'; ...
                        'Mean velocity'; 'Saccade Type'; ...
                        'Saccade Group'; 'Gain'; ...
                        'Accuracy'; 'Latency'; 'minAcc'; ...
                        'maxAcc'; 'Skewness'; 'Qratio'; 'Abruptness_acc'; 'Abruptness_dec'});
            
            else
                app.UITable.Data = array2table(ones(0, 19));
                app.UITable.Data.Properties.VariableNames = {'Gap ID'; 'Saccade ID'; ...
                    'Sample ID'; 'Time (start)'; 'Duration'; ...
                    'Amplitude (y)'; 'Peak velocity'; ...
                    'Mean velocity'; 'Saccade Type'; ...
                    'Saccade Group'; 'Gain'; ...
                    'Accuracy'; 'Latency'; 'minAcc'; ...
                    'maxAcc'; 'Skewness'; 'Qratio'; 'Abruptness_acc'; 'Abruptness_dec'};
            
            end
            
            app.experiment.saccadeListSelected = [];

            if ~isempty(app.experiment.saccadeList)
                app.experiment.saccadeList = array2table(app.experiment.saccadeList{[], ...
                   {'i', 'scnt', 's1', 's2', 'vPeak', 'vMean', ... %6
                       'sRT', 'ttP', 'dSac', 'distSac', 'distSacx', 'distSacy', ... %12
                       'pctInvalid', 'durat', 'dSacx', 'dSacy', 'vxPeak', 'vxMean', ... %18
                       'vyPeak', 'vyMean', 'aMean_1', 'pctInvalidx', 'pctInvalidy', ... %23
                       'pctInvalidtx', 'pctInvalidty', 'distxPeakRifMax', ... %26
                       'distxPeakRifMin', 'distyPeakRifMax', 'distyPeakRifMin', ... %29
                       'idistxPeakMax', 'idistxPeakMin', 'idistyPeakMax', ... %32
                       'idistyPeakMin', 't1', 't2', 'aPeak1_1', 'aPeak1_2', 'aMean_2', ...
                       'axPeak1_1', 'axPeak1_2', 'axMean', ... %41 (aPeak1 and axPeak1 are couples)
                       'ayPeak1_1', 'ayPeak1_2', 'ayMean', 'col4', ...
                       'skewness_x', 'skewness_y', 'Q_ratio_x', 'Q_ratio_y', ... %49
                       'abruptness_x_acc', 'abruptness_x_dec', 'abruptness_y_acc', 'abruptness_y_dec'}},...
                       'VariableNames', {'i', 'scnt', 's1', 's2', 'vPeak', 'vMean', ... %6
                       'sRT', 'ttP', 'dSac', 'distSac', 'distSacx', 'distSacy', ... %12
                       'pctInvalid', 'durat', 'dSacx', 'dSacy', 'vxPeak', 'vxMean', ... %18
                       'vyPeak', 'vyMean', 'aMean_1', 'pctInvalidx', 'pctInvalidy', ... %23
                       'pctInvalidtx', 'pctInvalidty', 'distxPeakRifMax', ... %26
                       'distxPeakRifMin', 'distyPeakRifMax', 'distyPeakRifMin', ... %29
                       'idistxPeakMax', 'idistxPeakMin', 'idistyPeakMax', ... %32
                       'idistyPeakMin', 't1', 't2', 'aPeak1_1', 'aPeak1_2', 'aMean_2', ...
                       'axPeak1_1', 'axPeak1_2', 'axMean', ... %41 (aPeak1 and axPeak1 are couples)
                       'ayPeak1_1', 'ayPeak1_2', 'ayMean', 'col4', ...
                       'skewness_x', 'skewness_y', 'Q_ratio_x', 'Q_ratio_y', ... %49
                       'abruptness_x_acc', 'abruptness_x_dec', 'abruptness_y_acc', 'abruptness_y_dec'});
    
             else
                app.experiment.saccadeList = array2table(ones(0, 53));
                app.experiment.saccadeList.Properties.VariableNames = ...
                      {'i', 'scnt', 's1', 's2', 'vPeak', 'vMean', ... %6
                       'sRT', 'ttP', 'dSac', 'distSac', 'distSacx', 'distSacy', ... %12
                       'pctInvalid', 'durat', 'dSacx', 'dSacy', 'vxPeak', 'vxMean', ... %18
                       'vyPeak', 'vyMean', 'aMean_1', 'pctInvalidx', 'pctInvalidy', ... %23
                       'pctInvalidtx', 'pctInvalidty', 'distxPeakRifMax', ... %26
                       'distxPeakRifMin', 'distyPeakRifMax', 'distyPeakRifMin', ... %29
                       'idistxPeakMax', 'idistxPeakMin', 'idistyPeakMax', ... %32
                       'idistyPeakMin', 't1', 't2', 'aPeak1_1', 'aPeak1_2', 'aMean_2', ...
                       'axPeak1_1', 'axPeak1_2', 'axMean', ... %41 (aPeak1 and axPeak1 are couples)
                       'ayPeak1_1', 'ayPeak1_2', 'ayMean', 'col4', ...
                       'skewness_x', 'skewness_y', 'Q_ratio_x', 'Q_ratio_y', ... %49
                       'abruptness_x_acc', 'abruptness_x_dec', 'abruptness_y_acc', 'abruptness_y_dec'};
            
            end    
        end

        % Value changed function: PhaseplaneCheckBox
        function PhaseplaneCheckBoxValueChanged(app, event)
            value = app.PhaseplaneCheckBox.Value;
            
            if value == 1
               app.matCheckBox.Value = 1;
                
            else
                
            end
            
        end

        % Value changed function: matCheckBox
        function matCheckBoxValueChanged(app, event)
            value = app.matCheckBox.Value;
            if app.PhaseplaneCheckBox.Value == 1
                app.matCheckBox.Value = 1;
                
            end
        end

        % Button pushed function: refreshMetrics
        function refreshMetricsPushed(app, event)
            % Recompute the metrics on the selected file (Hypothetically
            % the metrics coputation algo have changed and one wants to
            % recompute the metrics on an old mat file)
            
            % saccade detection
            saccade_report(app); % Call the scripts to detect the saccades and to compute the relevant metrics
            
            if ~isempty(app.experiment.saccadeList)
                add_column_type(app);
            
                add_column_gain(app);
                add_column_accuracy(app);
                add_column_latency(app);
                add_column_gap_id(app);
            
            end
            
            % finally, update table in the gui
            updatetable(app);           

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_4 = uipanel(app.UIFigure);
            app.UpdateButton = uibutton(app.Panel_4, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.BlinkoffsetEditFieldLabel = uilabel(app.Panel_4);
            app.BlinkoffsetEditFieldLabel.Text = 'Blink offset';
            app.BlinkoffsetEditField = uieditfield(app.Panel_4, 'numeric');
            app.BlinkoffsetEditField.Limits = [0 500];
            app.BlinkoffsetEditField.RoundFractionalValues = 'on';
            app.BlinkoffsetEditField.Value = 40;
            app.ScreenResolutionEditFieldLabel = uilabel(app.Panel_4);
            app.ScreenResolutionEditFieldLabel.Text = 'Screen Resolution';
            app.ScreenResolutionCols = uieditfield(app.Panel_4, 'numeric');
            app.ScreenResolutionCols.Value = 1920;
            app.ScreenResolutionRows = uieditfield(app.Panel_4, 'numeric');
            app.ScreenResolutionRows.Value = 1080;
            app.xEditFieldLabel = uilabel(app.Panel_4);
            app.xEditFieldLabel.Text = 'x';
            app.DistanceeyescreenLabel = uilabel(app.Panel_4);
            app.DistanceeyescreenLabel.Text = 'Distance eye-screen';
            app.eyeScreenDistance = uieditfield(app.Panel_4, 'numeric');
            app.eyeScreenDistance.Value = 845;
            app.ScreenSizeLabel = uilabel(app.Panel_4);
            app.ScreenSizeLabel.Text = 'Screen Size';
            app.ScreenWidth = uieditfield(app.Panel_4, 'numeric');
            app.ScreenWidth.Value = 620;
            app.ScreenHeight = uieditfield(app.Panel_4, 'numeric');
            app.ScreenHeight.Value = 342;
            app.xEditFieldLabel_2 = uilabel(app.Panel_4);
            app.xEditFieldLabel_2.Text = 'x';
            app.MaxLatencySpinnerLabel = uilabel(app.Panel_4);
            app.MaxLatencySpinnerLabel.Text = 'Max. Latency';
            app.MaxLatencySpinner = uispinner(app.Panel_4);
            app.MaxLatencySpinner.Value = 500;
            app.MinLatencySpinnerLabel = uilabel(app.Panel_4);
            app.MinLatencySpinnerLabel.Text = 'Min. Latency';
            app.MinLatencySpinner = uispinner(app.Panel_4);
            app.MinLatencySpinner.Value = 80;
            app.MaxDurationSpinnerLabel = uilabel(app.Panel_4);
            app.MaxDurationSpinnerLabel.Text = 'Max. Duration';
            app.MaxDurationSpinner = uispinner(app.Panel_4);
            app.MaxDurationSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxDurationSpinnerValueChanged, true);
            app.MaxDurationSpinner.Value = 500;
            app.SaccTypeDropDownLabel = uilabel(app.Panel_4);
            app.SaccTypeDropDownLabel.Enable = 'off';
            app.SaccTypeDropDownLabel.Visible = 'off';
            app.SaccTypeDropDownLabel.Text = 'Sacc. Type';
            app.SaccTypeDropDown = uidropdown(app.Panel_4);
            app.SaccTypeDropDown.Items = {'All', '10', '18', '8'};
            app.SaccTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SaccTypeDropDownValueChanged, true);
            app.SaccTypeDropDown.Enable = 'off';
            app.SaccTypeDropDown.Visible = 'off';
            app.SaccTypeDropDown.Value = 'All';
            app.MinDurationSpinnerLabel = uilabel(app.Panel_4);
            app.MinDurationSpinnerLabel.Text = 'Min. Duration';
            app.MinDurationSpinner = uispinner(app.Panel_4);
            app.MinDurationSpinner.ValueChangedFcn = createCallbackFcn(app, @MinDurationSpinnerValueChanged, true);
            app.MaxAmplitudeSpinnerLabel = uilabel(app.Panel_4);
            app.MaxAmplitudeSpinnerLabel.Text = 'Max. Amplitude';
            app.MaxAmplitudeSpinner = uispinner(app.Panel_4);
            app.MaxAmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxAmplitudeSpinnerValueChanged, true);
            app.MaxAmplitudeSpinner.Value = 25;
            app.MinAmplitudeSpinnerLabel = uilabel(app.Panel_4);
            app.MinAmplitudeSpinnerLabel.Text = 'Min. Amplitude';
            app.MinAmplitudeSpinner = uispinner(app.Panel_4);
            app.MinAmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @MinAmplitudeSpinnerValueChanged, true);
            app.MinAmplitudeSpinner.Value = 7;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'ns.png';
            app.NeuroSenseJointLabSienaSIItalyLabel = uilabel(app.UIFigure);
            app.NeuroSenseJointLabSienaSIItalyLabel.Text = {'NeuroSense Joint Lab.'; 'Siena, SI (Italy)'};
            app.Panel_5 = uipanel(app.UIFigure);
            app.DeviceDropDownLabel = uilabel(app.Panel_5);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.Panel_5);
            app.DeviceDropDown.Items = {'Select a value', 'EyeLink Portable Duo', 'ASL', 'old analysis'};
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.DeviceDropDown.Value = 'Select a value';
            app.ExperimentDropDownLabel = uilabel(app.Panel_5);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.Panel_5);
            app.ExperimentDropDown.Items = {'Select Experiment Type', 'Horizontal Saccades', 'Vertical Saccades'};
            app.ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperimentDropDownValueChanged, true);
            app.ExperimentDropDown.Enable = 'off';
            app.ExperimentDropDown.Value = 'Select Experiment Type';
            app.CurrentFileEditField = uieditfield(app.Panel_5, 'text');
            app.CurrentFileEditField.Editable = 'off';
            app.CurrentFileEditField.Enable = 'off';
            app.SelectFileButton = uibutton(app.Panel_5, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectFileButton.Enable = 'off';
            app.SelectFileButton.Text = 'Select File';
            app.ResetButton = uibutton(app.Panel_5, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.Panel_6 = uipanel(app.UIFigure);
            app.RemovereturnsaccadesCheckBox = uicheckbox(app.Panel_6);
            app.RemovereturnsaccadesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemovereturnsaccadesCheckBoxValueChanged, true);
            app.RemovereturnsaccadesCheckBox.Enable = 'off';
            app.RemovereturnsaccadesCheckBox.Text = 'Remove return saccades';
            app.refreshMetrics = uibutton(app.Panel_6, 'push');
            app.refreshMetrics.ButtonPushedFcn = createCallbackFcn(app, @refreshMetricsPushed, true);
            app.refreshMetrics.Icon = 'reset.png';
            app.refreshMetrics.Enable = 'off';
            app.refreshMetrics.Text = '';
            app.refreshmetricsLabel = uilabel(app.Panel_6);
            app.refreshmetricsLabel.Enable = 'off';
            app.refreshmetricsLabel.Text = 'refresh metrics';
            app.Panel_2 = uipanel(app.Panel_6);
            app.RemoveButton = uibutton(app.Panel_2, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Text = 'Remove';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Text = '⎌';
            app.UITable = uitable(app.Panel_6);
            app.UITable.ColumnName = {'Gap ID'; 'Saccade ID'; 'Sample ID'; 'Time (start)'; 'Duration'; 'Amplitude'; 'Peak velocity'; 'Mean velocity'; 'Saccade Type'; 'Saccade Group'; 'Gain'; 'Accuracy'; 'Latency'; 'minAcc'; 'maxAcc'; 'Skewness'; 'QRatio'; 'Abruptness_acc'; 'Abruptness_dec'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Enable = 'off';
            app.AddButton = uibutton(app.Panel_6, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            app.UIAxes = uiaxes(app.Panel_6);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Gaze')
            app.Panel_3 = uipanel(app.UIFigure);
            app.matCheckBox = uicheckbox(app.Panel_3);
            app.matCheckBox.ValueChangedFcn = createCallbackFcn(app, @matCheckBoxValueChanged, true);
            app.matCheckBox.Enable = 'off';
            app.matCheckBox.Text = '.mat';
            app.figCheckBox = uicheckbox(app.Panel_3);
            app.figCheckBox.Enable = 'off';
            app.figCheckBox.Text = '.fig';
            app.PhaseplaneCheckBox = uicheckbox(app.Panel_3);
            app.PhaseplaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhaseplaneCheckBoxValueChanged, true);
            app.PhaseplaneCheckBox.Enable = 'off';
            app.PhaseplaneCheckBox.Text = 'Phase plane';
            app.SavereportButton = uibutton(app.Panel_3, 'state');
            app.SavereportButton.ValueChangedFcn = createCallbackFcn(app, @SavereportButtonValueChanged, true);
            app.SavereportButton.Enable = 'off';
            app.SavereportButton.Text = 'Save report';
            app.MainsequenceCheckBox = uicheckbox(app.Panel_3);
            app.MainsequenceCheckBox.Enable = 'off';
            app.MainsequenceCheckBox.Text = 'Main sequence';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaccadeAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end